classdef INTERFAZVBX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InformacindelproyectoTab        matlab.ui.container.Tab
        InformacingeneralPanel          matlab.ui.container.Panel
        DescripcinEditFieldLabel        matlab.ui.control.Label
        DescripcinEditField             matlab.ui.control.EditField
        TtulodelproyectoEditFieldLabel  matlab.ui.control.Label
        TtulodelproyectoEditField       matlab.ui.control.EditField
        LugarEditFieldLabel             matlab.ui.control.Label
        LugarEditField                  matlab.ui.control.EditField
        FechaEditFieldLabel             matlab.ui.control.Label
        FechaEditField                  matlab.ui.control.EditField
        SiguienteButton_3               matlab.ui.control.Button
        SeleccionarArchivoButton        matlab.ui.control.Button
        CargueunarchivovboLabel         matlab.ui.control.Label
        CargaLamp_2Label                matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ElijaelarchivoacargarLabel      matlab.ui.control.Label
        DelimitacindelproyectoTab       matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        ProgresivasdelproyectoPanel     matlab.ui.container.Panel
        Editprogresiva_2                matlab.ui.control.Button
        AbscisafinalEditFieldLabel      matlab.ui.control.Label
        Editprogresiva                  matlab.ui.control.Button
        AbscisafinalEditField           matlab.ui.control.NumericEditField
        AbscisaInicialEditFieldLabel    matlab.ui.control.Label
        AbscisaInicialEditField         matlab.ui.control.NumericEditField
        RecalcularButton                matlab.ui.control.Button
        DistanciaentretramosEditFieldLabel  matlab.ui.control.Label
        DistanciaentretramosEditField   matlab.ui.control.NumericEditField
        NmerodetramosEditFieldLabel     matlab.ui.control.Label
        NmerodetramosEditField          matlab.ui.control.NumericEditField
        SiguienteButton_4               matlab.ui.control.Button
        AtrsButton                      matlab.ui.control.Button
        CargueelvideoparalaextracindeimgenesdecadatramoLabel  matlab.ui.control.Label
        SeleccionararchivoButton        matlab.ui.control.Button
        CargaLamp_2Label_2              matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
        AnlisisdetramosTab              matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        AnalizarButton_2                matlab.ui.control.Button
        TabGroup_2                      matlab.ui.container.TabGroup
        GrficaTramoTab                  matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        RadiomEditFieldLabel            matlab.ui.control.Label
        RadiomEditField                 matlab.ui.control.NumericEditField
        RadioButton                     matlab.ui.control.Button
        CRButton                        matlab.ui.control.Button
        Tramo_Curva                     matlab.ui.control.UIAxes
        GrficaPendienteTab              matlab.ui.container.Tab
        PendienteLabel                  matlab.ui.control.Label
        PendienteEditField              matlab.ui.control.NumericEditField
        EditarButton                    matlab.ui.control.Button
        Pendiente_Analisis              matlab.ui.control.UIAxes
        GuardardatosdeltramoButton      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        deLabel                         matlab.ui.control.Label
        AtrsButton_2                    matlab.ui.control.Button
        SeleccionetramodeanlisisSpinnerLabel  matlab.ui.control.Label
        SeleccionetramodeanlisisSpinner  matlab.ui.control.Spinner
        Grafica_Analisis                matlab.ui.control.UIAxes
        Tramo_Analisis                  matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeleccionarArchivoButton
        function SeleccionarArchivoButtonPushed2(app, event)
            msgbox('Espere, cargando.....','Mensaje')
           clear('../mat/preproceso_datos_video')
            F_vboload;
            msgbox('Operación Completa','Mensaje',"modal");
            app.Lamp.Color='green';
        end

        % Button pushed function: SiguienteButton_3
        function SiguienteButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.DelimitacindelproyectoTab;
            global vbo;
            if (0 == size(vbo,1))
            	error 'Load a VBO file first.';
            end
            Descrip=app.DescripcinEditField.Value;
            Fecha=app.FechaEditField.Value;
            Lugar=app.LugarEditField.Value;
            T_proyecto=app.TtulodelproyectoEditField.Value;
            clear('../mat/preproceso_matriz')
            %clear('../mat/preproceso_tramo')
            save('../mat/preproceso_datos_video','vbo')
            Distancia = vbo.channels(14).data;
            Absini = Distancia(1,1);
            Absfin = Distancia(end,1);
            app.AbscisaInicialEditField.Value = Absini;
            app.AbscisafinalEditField.Value = Absfin;
            inicio = app.AbscisaInicialEditField.Value;
            final =app.AbscisafinalEditField.Value;
            app.DistanciaentretramosEditField.Value;
            intervalotra = app.DistanciaentretramosEditField.Value;
            intervalo = inicio:intervalotra:final;
            [minimo,position]=min(abs(Distancia-(intervalo)));
            Prox=position(:);
            DF_DISTANCIA = Prox(end,1);
            DF_dist = Distancia(end,1)-Distancia(DF_DISTANCIA,1);
            Pos_fina = numel(Distancia);
            if app.DistanciaentretramosEditField.Value < 100
                AD_df_dist = DF_dist*.1;
            else
                AD_df_dist = DF_dist*.01;
            end
            if DF_dist < app.DistanciaentretramosEditField.Value-2
                TRAEX = numel(Prox);
                Numerotramos_1 = numel(Prox)-1;
                Tram_real = Numerotramos_1 + AD_df_dist;
                app.NmerodetramosEditField.Value= Tram_real;
                Prox = Prox.';
                Prox = [Prox Pos_fina];
                Prox = Prox.';
            else
                TRAEX = numel(Prox)-1;
                app.NmerodetramosEditField.Value= TRAEX;
                Prox = Prox ;
            end
            tramo(TRAEX).Tramos = [];
            tramo(TRAEX).Distancia = [];
            tramo(TRAEX).Pendiente = [];
            tramo(TRAEX).Radio = [];
            tramo(TRAEX).Xt=[];
            tramo(TRAEX).yt=[];
            %% Grafica
            latitud = vbo.channels(3).data;
            longitud = vbo.channels(4).data;
            inicio_tramo_grafica = Prox(1,1);
            fin_tramo_grafica = Prox(end,1);
            Xt=longitud(inicio_tramo_grafica:fin_tramo_grafica,1);
            yt=latitud(inicio_tramo_grafica:fin_tramo_grafica,1);
            plot(app.UIAxes,Xt,yt);
            save('../mat/preproceso_matriz',"Prox","tramo","intervalotra");
            save('../mat/Inf_proyecto',"Descrip","Fecha","Lugar","T_proyecto");
        end

        % Button pushed function: Editprogresiva
        function EditprogresivaPushed(app, event)
            text_cuadro ={'Ingrese la progresiva inicial : '};
            tit_cuadro = 'Progresiva Inicial';
            progresivaini={'0'};
            respuesta1=inputdlg(text_cuadro,tit_cuadro,1,progresivaini);
            app.AbscisaInicialEditField.Value=stRadio_2double(respuesta1);
           
        end

        % Button pushed function: Editprogresiva_2
        function Editprogresiva_2ButtonPushed(app, event)
            text_cuadro_fin ={'Ingrese la progresiva final : '};
            tit_cuadro_fin = 'Progresiva final';
            progresivafin={'0'};
            respuesta2=inputdlg( text_cuadro_fin,tit_cuadro_fin,1,progresivafin);
            app.AbscisafinalEditField.Value=stRadio_2double(respuesta2);
        end

        % Button pushed function: RecalcularButton
        function RecalcularButtonPushed(app, event)
            global vbo;
            if (0 == size(vbo,1))
            	error 'Load a VBO file first.';
            end
            clear('../mat/preproceso_matriz');
            clear('../mat/preproceso_tramo');
            Distancia = vbo.channels(14).data;
            Absfin = app.AbscisafinalEditField.Value;
            inicio = app.AbscisaInicialEditField.Value;
            final =app.AbscisafinalEditField.Value;
            app.DistanciaentretramosEditField.Value;
            intervalotra = app.DistanciaentretramosEditField.Value;
            intervalo = inicio:intervalotra:final;
            dif_int = Absfin-intervalo(1,end);
            dif_int_fal = intervalo(1,end)+dif_int;
            if dif_int > 0
                intervalo =[intervalo dif_int_fal];
 
            end
            [minimo,position]=min(abs(Distancia-(intervalo)));
            Prox=position(:);
            TRAEX = numel(Prox)-1;
            app.NmerodetramosEditField.Value=  TRAEX;
            
            tramo(TRAEX).Tramos = [];
            tramo(TRAEX).Distancia = [];
            tramo(TRAEX).Pendiente = [];
            tramo(TRAEX).Radio = [];
            tramo(TRAEX).Xt=[];
            tramo(TRAEX).yt=[];
            tramo(TRAEX).k=[];
            %% Grafica
            latitud = vbo.channels(3).data;
            longitud = vbo.channels(4).data;
            inicio_tramo_grafica = Prox(1,1);
            fin_tramo_grafica = Prox(end,1);
            Xt=longitud(inicio_tramo_grafica:fin_tramo_grafica,1);
            yt=latitud(inicio_tramo_grafica:fin_tramo_grafica,1);
            plot(app.UIAxes,Xt,yt);
            save('../mat/preproceso_matriz',"Prox","intervalotra");
            save('../mat/preproceso_tramo',"tramo");
            
        end

        % Button pushed function: SiguienteButton_4
        function SiguienteButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.AnlisisdetramosTab;
            maxdetramos = app.NmerodetramosEditField.Value;
            app.SeleccionetramodeanlisisSpinner.Limits = [1 maxdetramos];
            app.Label.Text= num2str(app.NmerodetramosEditField.Value);
        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            %%============================
            
            global vbo;
            if (0 == size(vbo,1))
            	error 'Load a VBO file first.';
            end
            clear('../mat/preproceso_tiempo_img');
            delete("../fotogramas/*");
            load('mat/preproceso_matriz');
            addpath("../data/Video/");
            %% INFORMACION
            [Filename, pathname]=uigetfile('*.avi');
            Video_Properties = VideoReader(Filename);
            Number_Frames_x_s = Video_Properties.FrameRate;
            %%
            
            tiempo=vbo.channels(13).data;
            TIEMPO_TRAMOS=tiempo(Prox,1)*Number_Frames_x_s;
            Time_tram= round(TIEMPO_TRAMOS);
            Time_trams = Time_tram;
            Time_trams(Time_trams==0)=1;
            save("../mat/preproceso_tiempo_img","Time_trams");
            
            %%
            ReadObj = VideoReader(Filename);
            
            CurFrame = 0;
            
            GetFrame =Time_trams;
            while hasFrame(ReadObj)
                
                CurImage = readFrame(ReadObj);
                
                CurFrame = CurFrame+1;
                
                if ismember(CurFrame, GetFrame)
                    
                    imwrite(CurImage, sprintf('../fotogramas/%d.jpg', CurFrame));
                    app.Lamp_2.Color='green';
                end
                
            end
            
            
        end

        % Button pushed function: AnalizarButton_2
        function AnalizarButton_2Pushed(app, event)
            %% Selección de tramo
            global vbo;
            if (0 == size(vbo,1))
            	error 'Load a VBO file first.';
            end
            
            clear('../mat/preproceso_matriz3');
            clear('../mat/preproceso_matriz1');
            clear('../mat/preproceso_matriz2');
            clear('../mat/preproceso_NRO_TRAMO');
            clear('../mat/preproceso_tramos');
            clear('../mat/preproceso_radio');
            load ('../mat/preproceso_matriz');
            load('../mat/preproceso_tiempo_img');
            
            NRO_TRAMO = app.SeleccionetramodeanlisisSpinner.Value;
            Tramoi=Prox(NRO_TRAMO,1);
            Tramof=Prox(NRO_TRAMO+1,1);
            %% Grafica general
            latitud = vbo.channels(3).data;
            longitud = vbo.channels(4).data;
            CoordX = longitud(Tramoi,1);
            CoordY= latitud(Tramoi,1);
            inicio_tramo_grafica = Prox(1,1);
            fin_tramo_grafica = Prox(end,1);
            longgg = longitud(inicio_tramo_grafica : fin_tramo_grafica);
            latiggg = latitud(inicio_tramo_grafica : fin_tramo_grafica);
            Xt=longitud(Tramoi:Tramof,1);
            yt=latitud(Tramoi:Tramof,1);
            plot(app.Grafica_Analisis,longgg,latiggg,'green',Xt,yt,'red','LineWidth',2);
            
            %%Pendiente
            velocidadH = vbo.channels(5).data;
            
            velocidadV = vbo.channels(8).data;
            
            distancia = vbo.channels(14).data;
            
            altura = vbo.channels(7).data;
            
            % Procesos
            % Proceso 1
            punto_inicial_curva_1 = altura(Tramoi,1);
            punto_inicial_final_1 = altura(Tramof,1);
            PIdistancia_punto_1 = distancia(Tramoi,1);
            Pfdistancia_punto_1 = distancia(Tramof,1);
            J = (punto_inicial_final_1-punto_inicial_curva_1)/(Pfdistancia_punto_1-PIdistancia_punto_1)*100;
            % Proceso 2
            Dvel = (velocidadV./velocidadH)*100;
            JPf = Dvel (Tramoi:Tramof,1);
            J2 = mean(JPf);
            
            %Pendiente final
            Jprom= (J+J2)/2;
            app.PendienteEditField.Value=Jprom;
            save('../mat/preproceso_pendiente',"Jprom");
            
            %Grafica de pendiente
            xvari =distancia(Tramoi:Tramof,1);
            yvari =altura(Tramoi:Tramof,1);
            plot(app.Pendiente_Analisis,xvari,yvari);
            %%Grafico del tramo
            Xt=longitud(Tramoi:Tramof,1);
            yt=latitud(Tramoi:Tramof,1);
            plot(app.Tramo_Curva,Xt,yt);
            text(app.Tramo_Curva,CoordX,CoordY,'\leftarrow Imagen del tramo','Color','r','FontSize',13);
            RF= 99999;
            app.RadiomEditField.Value=RF;
            Dist_tra_m = round(Pfdistancia_punto_1-PIdistancia_punto_1);
            if  Dist_tra_m == app.DistanciaentretramosEditField.Value+-2
                Dist_tra_m = app.DistanciaentretramosEditField.Value;
   
            end
            pos_tra = Time_trams(NRO_TRAMO,1);
            carga_img = "../fotogramas/";
            nom_img = sprintf('%d.jpg',pos_tra);
            ubi_nom = strcat(carga_img,nom_img);
            lec_img = imread(ubi_nom);
            imshow(lec_img,'Parent',app.Tramo_Analisis);
            
            
            save('../mat/preproceso_matriz1',"Xt","Dist_tra_m");
            save('../mat/preproceso_matriz2',"yt");
            save('../mat/preproceso_tramos',"NRO_TRAMO");
            save('../mat/preproceso_radio',"RF");
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ejex=load('../mat/preproceso_matriz1',"Xt");
            ejey=load('../mat/preproceso_matriz2');
            xvar =ejex.Xt;
            yvar =ejey.yt;
            
            
            %% Carga de datos
            
            global vbo;
            if (0 == size(vbo,1))
            	error 'Load a VBO file first.';
            end
            clear('../mat/preproceso_matriz3');
            clear('../img/graf.jpeg');
            %%
            fig = figure;
            plot(xvar,yvar);
            sgt = sgtitle('Selecciona punto medio de la curva','Color','red');
            sgt.FontSize =15;
            xlabel('x');
            ylabel('y');
            grid on;
            saveas(fig,'../img/graf.jpeg');
            
            %% Coordenadas de puntos seleccionados
            
            % Enable data cursor mode
            datacursormode on
            dcm_obj = datacursormode(fig);
            % Set update function
            set(dcm_obj,'UpdateFcn',@myupdatefcn);
            % Wait while the user to click
            pause
            % Export cursor to workspace
            info_struct = getCursorInfo(dcm_obj);
            if isfield(info_struct, 'Position')
                disp('Clicked positioin is')
                disp(info_struct.Position)
            end
            function output_txt = myupdatefcn(~,event_obj)
                % ~            Currently not used (empty)
                % event_obj    Object containing event data structure
                % output_txt   Data cursor text
                pos = get(event_obj, 'Position');
                output_txt = {['x: ' num2str(pos(1))], ['y: ' num2str(pos(2))]};
                
            end
            info_struct2 = info_struct;
            info_struct = info_struct.Position;
            
            save('../mat/preproceso_matriz3', 'info_struct');
            save('../mat/preproceso_matrizgra', 'info_struct2');
            close(fig);
        end

        % Button pushed function: RadioButton
        function RadioButtonPushed(app, event)
            global vbo;
            if (0 == size(vbo,1))
            	error 'Load a VBO file first.';
            end
            load('../mat/preproceso_matriz3');
            %%
            punto_partida_matriz = info_struct;
            
            
            %latitud
            punto_2_inicial=punto_partida_matriz(1,2);
            
            %% Posicion
            latitud = vbo.channels(3).data;
            
            %punto medio
            punto_medio_paRadio_1 = find(abs(latitud-punto_2_inicial)<0.00001);
            punto_medio_1 = punto_medio_paRadio_1(1,1);
            punto_inicial_curva_1=(punto_medio_1-20);
            punto_inicial_final_1=(punto_medio_1+22);
            %%
            distancia = vbo.channels(14).data;
            
            heading = vbo.channels(6).data;
            
            % % Valores de matricez de calculos
            % Distancias
            distancia_punto_1 = distancia(punto_inicial_curva_1,1);
            
            distancia_punto_3 = distancia(punto_inicial_final_1,1);
            % Heading
            heading_1 = heading(punto_inicial_curva_1,1);
            if heading_1<=100
                heading_1=heading_1+360;
                
            end
            
            
            heading_3 = heading(punto_inicial_final_1,1);
            if heading_3<=100
                heading_3=heading_3+360;
                
            end
            
            %Heading
            Radio_1 =abs((180*(distancia_punto_3-distancia_punto_1))/(pi*(heading_3-heading_1)));
            %%
            %Radio 2
            %punto medio
            punto_inicial_curva_1_2=(punto_medio_1-15);
            punto_inicial_final_1_2=(punto_medio_1+17);
            %%
            distancia = vbo.channels(14).data;
            
            heading = vbo.channels(6).data;
            % % Valores de matricez de calculos
            % Distancias
            distancia_punto_1 = distancia(punto_inicial_curva_1_2,1);
            
            distancia_punto_3 = distancia(punto_inicial_final_1_2,1);
            % Heading
            heading_1 = heading(punto_inicial_curva_1_2,1);
            if heading_1<=100
                heading_1=heading_1+360;
                
            end
            
            heading_3 = heading(punto_inicial_final_1_2,1);
            if heading_3<=100
                heading_3=heading_3+360;
                
            end
            
            Radio_2 =abs((180*(distancia_punto_3-distancia_punto_1))/(pi*(heading_3-heading_1)));
            %%
            %Radio 3
            %punto medio
            punto_inicial_curva_1_3=(punto_medio_1-5);
            punto_inicial_final_1_3=(punto_medio_1+7);
            %%
            distancia = vbo.channels(14).data;
            
            heading = vbo.channels(6).data;
            % % Valores de matricez de calculos
            % Distancias
            distancia_punto_1 = distancia(punto_inicial_curva_1_3,1);
            
            distancia_punto_3 = distancia(punto_inicial_final_1_3,1);
            % Heading
            heading_1 = heading(punto_inicial_curva_1_3,1);
            if heading_1<=100
                heading_1=heading_1+360;
                
            end
            
            heading_3 = heading(punto_inicial_final_1_3,1);
            if heading_3<=100
                heading_3=heading_3+360;
                
            end
            
            Radio_3 =abs((180*(distancia_punto_3-distancia_punto_1))/(pi*(heading_3-heading_1)));
            %%
            %Radio final
            RF=double((Radio_1+Radio_2+Radio_3)/3);
            app.RadiomEditField.Value=RF;
            save('../mat/preproceso_radio','RF');
            
        end

        % Button pushed function: GuardardatosdeltramoButton
        function GuardardatosdeltramoButtonPushed(app, event)
            global vbo;
            if (0 == size(vbo,1))
            	error 'Load a VBO file first.';
            end
            load('../mat/preproceso_tramos');
            load('../mat/preproceso_tramo');
            load('../mat/preproceso_pendiente');
            load('../mat/preproceso_radio');
            load('../mat/preproceso_matriz1');
            load('../mat/preproceso_matriz2');
            k=RF;
            if RF<99999
                k=1;
            else
                RF=99999;
                k=0;
            end
            
            %%
            %% ALMACENAMIENTO POR CADA TRAMO
            % Ejecutar cuando ya se tenga la información de k, Jprom, RF
            tramo(NRO_TRAMO).Tramos = NRO_TRAMO;
            tramo(NRO_TRAMO).Distancia = Dist_tra_m;
            tramo(NRO_TRAMO).Pendiente = Jprom;
            tramo(NRO_TRAMO).Radio = RF;
            tramo(NRO_TRAMO).Xt = Xt;
            tramo(NRO_TRAMO).yt = yt;
            tramo(NRO_TRAMO).k = k;
            %% VISUALIZACIÓN DE INFORMACIÓN
            
            % Visualiza todos los valroes de Jprom
            tramo.Pendiente;
            
            % Visualiza todos los valores de rf
            tramo.Radio;
            
            
            save('../mat/preproceso_tramo',"tramo");
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.TabGroup.SelectedTab = app.InformacindelproyectoTab   ;
        end

        % Button pushed function: EditarButton
        function EditarButtonPushed(app, event)
            camb1 ={'Ingrese la pendiente%: '};
            tit1 = 'Pendiente %';
            Pendiente={'0'};
            respuesta1=inputdlg(camb1,tit1,1,Pendiente);
            app.PendienteEditField.Value=stRadio_2double(respuesta1);
        end

        % Button pushed function: AtrsButton
        function AtrsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InformacindelproyectoTab ;
        end

        % Button pushed function: AtrsButton_2
        function AtrsButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.DelimitacindelproyectoTab;
        end

        % Button pushed function: CRButton
        function CRButtonPushed(app, event)
            imagen=imshow(imread('../img/graf.jpeg'));
            h = drawcircle('Color','k','FaceAlpha',0.4);
            h.StripeColor = 'magenta'; 
            h.FaceAlpha = 0.8;
            RF=h.Radius;
            app.RadiomEditField.Value=RF;
         
            save('../mat/preproceso_radio','RF');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            copyfile('../mat/preproceso_tramo.mat','../doc/');
            copyfile('../mat/preproceso_datos_video.mat','../doc/');
            copyfile('../mat/preproceso_tiempo_img.mat','../doc/');
            copyfile('../mat/Inf_proyecto.mat','../doc/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.InformacindelproyectoTab = uitab(app.TabGroup);
            app.InformacindelproyectoTab.Title = 'Información del proyecto';
            app.InformacingeneralPanel = uipanel(app.InformacindelproyectoTab);
            app.InformacingeneralPanel.Title = 'Información general';
            app.DescripcinEditFieldLabel = uilabel(app.InformacingeneralPanel);
            app.DescripcinEditFieldLabel.Text = 'Descripción:';
            app.DescripcinEditField = uieditfield(app.InformacingeneralPanel, 'text');
            app.TtulodelproyectoEditFieldLabel = uilabel(app.InformacingeneralPanel);
            app.TtulodelproyectoEditFieldLabel.Text = 'Título del proyecto:';
            app.TtulodelproyectoEditField = uieditfield(app.InformacingeneralPanel, 'text');
            app.LugarEditFieldLabel = uilabel(app.InformacingeneralPanel);
            app.LugarEditFieldLabel.Text = 'Lugar:';
            app.LugarEditField = uieditfield(app.InformacingeneralPanel, 'text');
            app.FechaEditFieldLabel = uilabel(app.InformacingeneralPanel);
            app.FechaEditFieldLabel.Text = 'Fecha:';
            app.FechaEditField = uieditfield(app.InformacingeneralPanel, 'text');
            app.SiguienteButton_3 = uibutton(app.InformacingeneralPanel, 'push');
            app.SiguienteButton_3.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButton_3Pushed, true);
            app.SiguienteButton_3.Text = 'Siguiente';
            app.SeleccionarArchivoButton = uibutton(app.InformacingeneralPanel, 'push');
            app.SeleccionarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarArchivoButtonPushed2, true);
            app.SeleccionarArchivoButton.Text = 'Seleccionar Archivo';
            app.CargueunarchivovboLabel = uilabel(app.InformacingeneralPanel);
            app.CargueunarchivovboLabel.Text = 'Cargue un archivo (.vbo):';
            app.CargaLamp_2Label = uilabel(app.InformacingeneralPanel);
            app.CargaLamp_2Label.Text = 'Carga';
            app.Lamp = uilamp(app.InformacingeneralPanel);
            app.ElijaelarchivoacargarLabel = uilabel(app.InformacingeneralPanel);
            app.ElijaelarchivoacargarLabel.Text = 'Elija el archivo a cargar:';
            app.DelimitacindelproyectoTab = uitab(app.TabGroup);
            app.DelimitacindelproyectoTab.Title = 'Delimitación del proyecto';
            app.Panel_3 = uipanel(app.DelimitacindelproyectoTab);
            app.ProgresivasdelproyectoPanel = uipanel(app.Panel_3);
            app.ProgresivasdelproyectoPanel.Title = 'Progresivas del proyecto:';
            app.Editprogresiva_2 = uibutton(app.ProgresivasdelproyectoPanel, 'push');
            app.Editprogresiva_2.ButtonPushedFcn = createCallbackFcn(app, @Editprogresiva_2ButtonPushed, true);
            app.Editprogresiva_2.Text = '...';
            app.AbscisafinalEditFieldLabel = uilabel(app.ProgresivasdelproyectoPanel);
            app.AbscisafinalEditFieldLabel.Text = 'Abscisa final:';
            app.Editprogresiva = uibutton(app.ProgresivasdelproyectoPanel, 'push');
            app.Editprogresiva.ButtonPushedFcn = createCallbackFcn(app, @EditprogresivaPushed, true);
            app.Editprogresiva.Text = '...';
            app.AbscisafinalEditField = uieditfield(app.ProgresivasdelproyectoPanel, 'numeric');
            app.AbscisafinalEditField.ValueDisplayFormat = '%.0f';
            app.AbscisaInicialEditFieldLabel = uilabel(app.ProgresivasdelproyectoPanel);
            app.AbscisaInicialEditFieldLabel.Text = 'Abscisa Inicial:';
            app.AbscisaInicialEditField = uieditfield(app.ProgresivasdelproyectoPanel, 'numeric');
            app.RecalcularButton = uibutton(app.Panel_3, 'push');
            app.RecalcularButton.ButtonPushedFcn = createCallbackFcn(app, @RecalcularButtonPushed, true);
            app.RecalcularButton.Text = 'Re-calcular';
            app.DistanciaentretramosEditFieldLabel = uilabel(app.Panel_3);
            app.DistanciaentretramosEditFieldLabel.Text = 'Distancia entre tramos:';
            app.DistanciaentretramosEditField = uieditfield(app.Panel_3, 'numeric');
            app.DistanciaentretramosEditField.Value = 100;
            app.NmerodetramosEditFieldLabel = uilabel(app.Panel_3);
            app.NmerodetramosEditFieldLabel.Text = 'Número de tramos:';
            app.NmerodetramosEditField = uieditfield(app.Panel_3, 'numeric');
            app.NmerodetramosEditField.RoundFractionalValues = 'on';
            app.NmerodetramosEditField.ValueDisplayFormat = '%.1f';
            app.NmerodetramosEditField.Editable = 'off';
            app.SiguienteButton_4 = uibutton(app.Panel_3, 'push');
            app.SiguienteButton_4.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButton_4Pushed, true);
            app.SiguienteButton_4.Text = 'Siguiente';
            app.AtrsButton = uibutton(app.Panel_3, 'push');
            app.AtrsButton.ButtonPushedFcn = createCallbackFcn(app, @AtrsButtonPushed, true);
            app.AtrsButton.Text = 'Atrás';
            app.CargueelvideoparalaextracindeimgenesdecadatramoLabel = uilabel(app.Panel_3);
            app.CargueelvideoparalaextracindeimgenesdecadatramoLabel.Text = 'Cargue el video para la extración de imágenes de cada tramo';
            app.SeleccionararchivoButton = uibutton(app.Panel_3, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.CargaLamp_2Label_2 = uilabel(app.Panel_3);
            app.CargaLamp_2Label_2.Text = 'Carga';
            app.Lamp_2 = uilamp(app.Panel_3);
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, 'Recorrido')
            app.AnlisisdetramosTab = uitab(app.TabGroup);
            app.AnlisisdetramosTab.Title = 'Análisis de tramos';
            app.Panel_4 = uipanel(app.AnlisisdetramosTab);
            app.AnalizarButton_2 = uibutton(app.Panel_4, 'push');
            app.AnalizarButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButton_2Pushed, true);
            app.AnalizarButton_2.Text = 'Analizar';
            app.TabGroup_2 = uitabgroup(app.Panel_4);
            app.GrficaTramoTab = uitab(app.TabGroup_2);
            app.GrficaTramoTab.Title = 'Gráfica Tramo';
            app.Button = uibutton(app.GrficaTramoTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Sharp.png';
            app.Button.Tooltip = {'En caso de existir curva, seleccione el punto medio.'};
            app.Button.Text = '';
            app.RadiomEditFieldLabel = uilabel(app.GrficaTramoTab);
            app.RadiomEditFieldLabel.Text = 'Radio  (m)';
            app.RadiomEditField = uieditfield(app.GrficaTramoTab, 'numeric');
            app.RadiomEditField.ValueDisplayFormat = '%.0f';
            app.RadiomEditField.Value = 999999;
            app.RadioButton = uibutton(app.GrficaTramoTab, 'push');
            app.RadioButton.ButtonPushedFcn = createCallbackFcn(app, @RadioButtonPushed, true);
            app.RadioButton.Tooltip = {'Calcular radio.'};
            app.RadioButton.Text = 'Radio';
            app.CRButton = uibutton(app.GrficaTramoTab, 'push');
            app.CRButton.ButtonPushedFcn = createCallbackFcn(app, @CRButtonPushed, true);
            app.CRButton.Tooltip = {'Opcional. Circulo de referencia para curvas pequeñas. '};
            app.CRButton.Text = 'CR';
            app.Tramo_Curva = uiaxes(app.GrficaTramoTab);
            app.GrficaPendienteTab = uitab(app.TabGroup_2);
            app.GrficaPendienteTab.Title = 'Gráfica Pendiente';
            app.PendienteLabel = uilabel(app.GrficaPendienteTab);
            app.PendienteLabel.Text = 'Pendiente % ';
            app.PendienteEditField = uieditfield(app.GrficaPendienteTab, 'numeric');
            app.EditarButton = uibutton(app.GrficaPendienteTab, 'push');
            app.EditarButton.ButtonPushedFcn = createCallbackFcn(app, @EditarButtonPushed, true);
            app.EditarButton.Text = 'Editar';
            app.Pendiente_Analisis = uiaxes(app.GrficaPendienteTab);
            app.GuardardatosdeltramoButton = uibutton(app.Panel_4, 'push');
            app.GuardardatosdeltramoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardardatosdeltramoButtonPushed, true);
            app.GuardardatosdeltramoButton.Text = 'Guardar datos del tramo';
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = '';
            app.deLabel = uilabel(app.Panel_4);
            app.deLabel.Text = 'de';
            app.AtrsButton_2 = uibutton(app.Panel_4, 'push');
            app.AtrsButton_2.ButtonPushedFcn = createCallbackFcn(app, @AtrsButton_2Pushed, true);
            app.AtrsButton_2.Text = 'Atrás';
            app.SeleccionetramodeanlisisSpinnerLabel = uilabel(app.Panel_4);
            app.SeleccionetramodeanlisisSpinnerLabel.Text = 'Seleccione tramo de análisis:';
            app.SeleccionetramodeanlisisSpinner = uispinner(app.Panel_4);
            app.SeleccionetramodeanlisisSpinner.Value = 1;
            app.Grafica_Analisis = uiaxes(app.Panel_4);
            title(app.Grafica_Analisis, 'Gráfica General del recorrido')
            app.Tramo_Analisis = uiaxes(app.Panel_4);
            title(app.Tramo_Analisis, 'Tramo')
            app.Tramo_Analisis.XTick = [];
            app.Tramo_Analisis.YTick = [];
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INTERFAZVBX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end