classdef app_Reporte < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Reporte                        matlab.ui.Figure
        TabGroup2                      matlab.ui.container.TabGroup
        ReporteGeneralTab              matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        CostadodelavaTab               matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        UITable_2                      matlab.ui.control.Table
        UITable_3                      matlab.ui.control.Table
        UITable_4                      matlab.ui.control.Table
        UITable_5                      matlab.ui.control.Table
        UITable_6                      matlab.ui.control.Table
        UITable_7                      matlab.ui.control.Table
        MedianaTab                     matlab.ui.container.Tab
        UITable_10                     matlab.ui.control.Table
        UITable_11                     matlab.ui.control.Table
        UITable_13                     matlab.ui.control.Table
        UITable_12                     matlab.ui.control.Table
        UITable_14                     matlab.ui.control.Table
        UITable_15                     matlab.ui.control.Table
        UITable_16                     matlab.ui.control.Table
        UITable_17                     matlab.ui.control.Table
        UITable_18                     matlab.ui.control.Table
        UITable_19                     matlab.ui.control.Table
        UITable_20                     matlab.ui.control.Table
        UITable_21                     matlab.ui.control.Table
        UITable_22                     matlab.ui.control.Table
        UITable_23                     matlab.ui.control.Table
        UITable_24                     matlab.ui.control.Table
        UITable_25                     matlab.ui.control.Table
        UITable_26                     matlab.ui.control.Table
        InterseccionesTab              matlab.ui.container.Tab
        UITable_27                     matlab.ui.control.Table
        UITable_28                     matlab.ui.control.Table
        UITable_29                     matlab.ui.control.Table
        UITable_30                     matlab.ui.control.Table
        UITable_31                     matlab.ui.control.Table
        FlujoTab                       matlab.ui.container.Tab
        UITable_32                     matlab.ui.control.Table
        UITable_33                     matlab.ui.control.Table
        UITable_34                     matlab.ui.control.Table
        UITable_35                     matlab.ui.control.Table
        UITable_36                     matlab.ui.control.Table
        UITable_37                     matlab.ui.control.Table
        InfraestructuraparausuariosvulnerablesyusodelsueloTab  matlab.ui.container.Tab
        UITable_38                     matlab.ui.control.Table
        UITable_39                     matlab.ui.control.Table
        UITable_40                     matlab.ui.control.Table
        UITable_41                     matlab.ui.control.Table
        UITable_42                     matlab.ui.control.Table
        UITable_43                     matlab.ui.control.Table
        UITable_44                     matlab.ui.control.Table
        UITable_45                     matlab.ui.control.Table
        UITable_46                     matlab.ui.control.Table
        UITable_47                     matlab.ui.control.Table
        UITable_48                     matlab.ui.control.Table
        UITable_49                     matlab.ui.control.Table
        UITable_50                     matlab.ui.control.Table
        VelocidadesTab                 matlab.ui.container.Tab
        UITable_51                     matlab.ui.control.Table
        UITable_52                     matlab.ui.control.Table
        UITable_53                     matlab.ui.control.Table
        UITable_54                     matlab.ui.control.Table
        ReportedetalladoTab            matlab.ui.container.Tab
        TabGroup_2                     matlab.ui.container.TabGroup
        ClasificacindeestrellasTab     matlab.ui.container.Tab
        UITable_9                      matlab.ui.control.Table
        UsuariosvialesButtonGroup      matlab.ui.container.ButtonGroup
        OcupantedevehculosButton       matlab.ui.control.RadioButton
        MotociclistaButton             matlab.ui.control.RadioButton
        CiclistaButton                 matlab.ui.control.RadioButton
        PeatnButton                    matlab.ui.control.RadioButton
        PDFButton_3                    matlab.ui.control.Button
        MapaPanel                      matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        DistanciaClasificacinporestrellasPanel  matlab.ui.container.Panel
        UIAxes_2                       matlab.ui.control.UIAxes
        SuavizadoCheckBox              matlab.ui.control.CheckBox
        MedidasdemejoramientoTab       matlab.ui.container.Tab
        DATOSGENERALESDELACARRETERAPanel  matlab.ui.container.Panel
        NombreTextAreaLabel            matlab.ui.control.Label
        NombreTextArea                 matlab.ui.control.TextArea
        TramosguardadosSpinnerLabel    matlab.ui.control.Label
        TramosguardadosSpinner         matlab.ui.control.Spinner
        ATRIBUTOSCODIFICADOSPanel      matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        CostadodelavaTab_2             matlab.ui.container.Tab
        UITable_55                     matlab.ui.control.Table
        MedianaTab_2                   matlab.ui.container.Tab
        UITable_56                     matlab.ui.control.Table
        InterseccionesTab_2            matlab.ui.container.Tab
        UITable_57                     matlab.ui.control.Table
        FlujoTab_2                     matlab.ui.container.Tab
        UITable_58                     matlab.ui.control.Table
        InfraestructuraparausuariosvulnerablesyusodelsueloTab_2  matlab.ui.container.Tab
        UITable_59                     matlab.ui.control.Table
        VelocidadesTab_2               matlab.ui.container.Tab
        UITable_60                     matlab.ui.control.Table
        ClasificacinporestrellasPanel  matlab.ui.container.Panel
        OcupantedevehculosEditField_3Label  matlab.ui.control.Label
        OcupantedevehculosEditField_3  matlab.ui.control.NumericEditField
        MotociclistaEditField_3Label   matlab.ui.control.Label
        MotociclistaEditField_3        matlab.ui.control.NumericEditField
        CiclistaEditField_3Label       matlab.ui.control.Label
        CiclistaEditField_3            matlab.ui.control.NumericEditField
        PeatnEditField_3Label          matlab.ui.control.Label
        PeatnEditField_3               matlab.ui.control.NumericEditField
        PuntajedeclasificacinPanel     matlab.ui.container.Panel
        OcupantedevehculosEditField_4Label  matlab.ui.control.Label
        OcupantedevehculosEditField_4  matlab.ui.control.NumericEditField
        CiclistaEditField_4Label       matlab.ui.control.Label
        CiclistaEditField_4            matlab.ui.control.NumericEditField
        MotociclistaEditField_4Label   matlab.ui.control.Label
        MotociclistaEditField_4        matlab.ui.control.NumericEditField
        PeatnEditField_4Label          matlab.ui.control.Label
        PeatnEditField_4               matlab.ui.control.NumericEditField
        MEDIDASADOPTADASPanel          matlab.ui.container.Panel
        UITable2                       matlab.ui.control.Table
        CalcularButton                 matlab.ui.control.Button
        PDFButton_2                    matlab.ui.control.Button
        PuntajedeclasificacinPanel_2   matlab.ui.container.Panel
        OcupantedevehculosEditField_5Label  matlab.ui.control.Label
        OcupantedevehculosEditField_5  matlab.ui.control.NumericEditField
        CiclistaEditField_5Label       matlab.ui.control.Label
        CiclistaEditField_5            matlab.ui.control.NumericEditField
        MotociclistaEditField_5Label   matlab.ui.control.Label
        MotociclistaEditField_5        matlab.ui.control.NumericEditField
        PeatnEditField_5Label          matlab.ui.control.Label
        PeatnEditField_5               matlab.ui.control.NumericEditField
        ClasificacinporestrellasPanel_2  matlab.ui.container.Panel
        OcupantedevehculosEditField_6Label  matlab.ui.control.Label
        OcupantedevehculosEditField_6  matlab.ui.control.NumericEditField
        MotociclistaEditField_6Label   matlab.ui.control.Label
        MotociclistaEditField_6        matlab.ui.control.NumericEditField
        CiclistaEditField_6Label       matlab.ui.control.Label
        CiclistaEditField_6            matlab.ui.control.NumericEditField
        PeatnEditField_6Label          matlab.ui.control.Label
        PeatnEditField_6               matlab.ui.control.NumericEditField
        GuardarButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        s1 % Description
        s2
        s3
        s4
        s5
        s6
        s7
        Datos_video
        Datos_interfaz_1
        Datos_interfaz_2
        b1
        Tabla_estrellas_Ocupante
        Tabla_estrellas_Motociclista
        Tabla_estrellas_Cilista
        Tabla_estrellas_Peaton
        Tabla_Nuevaestrellas_Ocupante
        Tabla_Nuevaestrellas_Motociclista
        Tabla_Nuevaestrellas_Cilista
        Tabla_Nuevaestrellas_Peaton
        Costado_de_via
        Mediana
        Intersecciones
        Flujo
        Infraestructura
        Velocidades
        Matriz
        
    end
    
    methods (Access = private)
        
        function results = buscar(app)
            app.OcupantedevehculosEditField_4.Value=app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo(app.b1);
            app.MotociclistaEditField_4.Value=app.Datos_interfaz_2.Puntuacion_Motociclista(app.b1);
            app.CiclistaEditField_4.Value=app.Datos_interfaz_2.Puntuacion_Ciclista(app.b1);
            app.PeatnEditField_4.Value=app.Datos_interfaz_2.Puntuacion_Peaton(app.b1);
            app.OcupantedevehculosEditField_3.Value=app.Datos_interfaz_2.Ocupante_del_vehiculo(app.b1);
            app.MotociclistaEditField_3.Value=app.Datos_interfaz_2.Motociclista(app.b1);
            app.CiclistaEditField_3.Value=app.Datos_interfaz_2.Ciclista(app.b1);
            app.PeatnEditField_3.Value=app.Datos_interfaz_2.Peaton(app.b1);
        end
        
        function results = buscar_1(app)
            a=app.Datos_interfaz_2;
            a1=cell2mat(a.Severidad_lateral_distancia_al_objeto_lado_del_conductor(app.b1));
            a2=cell2mat(a.Severidad_lateral_objeto_lado_del_conductor(app.b1));
            a3=cell2mat(a.Severidad_lateral_distancia_al_objeto_lado_del_copiloto(app.b1));
            a4=cell2mat(a.Severidad_lateral_objeto_lado_del_copiloto(app.b1));
            a5=cell2mat(a.Bandas_sonoras_en_el_espaldon(app.b1));
            a6=cell2mat(a.Ancho_de_espaldon_pavimentado_lado_del_conductor(app.b1));
            a7=cell2mat(a.Ancho_de_espaldon_pavimentado_lado_del_copiloto(app.b1));
            app.Costado_de_via={'Severidad lateral - distancia al objeto (lado del conductor)',a1;'Severidad lateral - objeto (lado del conductor)',a2;'Severidad lateral - distancia al objeto (lado del copiloto)',a3;'Severidad lateral - objeto (lado del copiloto)',a4;'Bandas sonoras en el espaldón',a5;'Ancho de espaldón pavimentado (lado del conductor)',a6;'Ancho de espaldón pavimentado (lado del copiloto)',a7;};
            app.UITable_55.Data=app.Costado_de_via;
            
            b=app.Datos_interfaz_2;
            B1=cell2mat(b.Tipo_de_carretera(app.b1));
            b2=cell2mat(b.Costo_de_modernizacion(app.b1));
            b3=cell2mat(b.Tipo_de_mediana(app.b1));
            b4=cell2mat(b.Bandas_sonoras_centrales(app.b1));
            b5=cell2mat(b.Numero_de_carriles(app.b1));
            b6=cell2mat(b.Ancho_de_carril(app.b1));
            b7=cell2mat(b.Curvatura(app.b1));
            b8=cell2mat(b.Calidad_de_la_curva(app.b1));
            b9=cell2mat(b.Pendiente(app.b1));
            b10=cell2mat(b.Estado_de_la_superficie_de_la_via(app.b1));
            b11=cell2mat(b.Resistencia_al_deslizamiento(app.b1));
            b12=cell2mat(b.Delineacion(app.b1));
            b13=cell2mat(b.Alumbrado_publico(app.b1));
            b14=cell2mat(b.Estacionamiento_para_vehiculos(app.b1));
            b15=cell2mat(b.Via_de_servicio(app.b1));
            b16=cell2mat(b.Obras_en_la_carretera(app.b1));
            b17=cell2mat(b.Distancia_de_visibilidad(app.b1));
            app.Mediana={'Tipo de carretera',B1;'Costo de modernización',b2;'Tipo de mediana',b3;'Bandas sonoras centrales',b4;'Número de carriles',b5;'Ancho de carril',b6;'Curvatura',b7;'Calidad de la curva',b8;'Pendiente',b9;'Estado de la superficie de la vía',b10;'Resistencia al deslizamiento',b11;'Delineación',b12;'Alumbrado público',b13;'Estacionamiento para vehículos',b14;'Vía de servicio',b15;'Obras en la carretera',b16;'Distancia de visibilidad',b17};
            app.UITable_56.Data=app.Mediana;
            
            c=app.Datos_interfaz_2;
            c1=cell2mat(c.Tipo_de_interseccion(app.b1));
            c2=cell2mat(c.Canalizacion_de_la_interseccion(app.b1));
            c3=cell2mat(c.Volumen_de_la_interseccion(app.b1));
            c4=cell2mat(c.Calidad_de_la_interseccion(app.b1));
            c5=cell2mat(c.Puntos_de_acceso_a_la_propiedad(app.b1));
            app.Intersecciones={'Tipo de intersección',c1;'Canalización de la intersección',c2;'Volumen de la intersección',c3;'Calidad de la intersección',c4;'Puntos de acceso a la propiedad',c5};
            app.UITable_57.Data=app.Intersecciones;
            
            d=app.Datos_interfaz_2;
            d1=cell2mat(d.Flujo_vehicular(app.b1));
            d2=cell2mat(d.Porcentaje_de_motocicletas(app.b1));
            d3=cell2mat(d.Flujo_peatonal_en_hora_pico_que_cruza_la_carretera(app.b1));
            d4=cell2mat(d.Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond(app.b1));
            d5=cell2mat(d.Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop(app.b1));
            d6=cell2mat(d.Flujo_de_bicicletas_en_hora_pico(app.b1));
            app.Flujo={'Flujo vehicular (TDPA)',d1;'% motocicletas',d2;'Flujo peatonal en hora pico que cruza la carretera',d3;'Flujo peatonal en hora pico a lo largo de la carretera (lado del conductor)',d4;'Flujo peatonal en hora pico a lo largo de la carretera (lado del copiloto)',d5;'Flujo de bicicletas en hora pico',d6};
            app.UITable_58.Data=app.Flujo;
            
            e=app.Datos_interfaz_2;
            e1=cell2mat(e.Uso_de_suelo_lado_del_conductor(app.b1));
            e2=cell2mat(e.Uso_de_suelo_lado_del_copiloto(app.b1));
            e3=cell2mat(e.Tipo_de_area(app.b1));
            e4=cell2mat(e.Infraestructura_de_cruce_peatonal_en_el_tramo_analizado(app.b1));
            e5=cell2mat(e.Calidad_de_cruce_peatonal(app.b1));
            e6=cell2mat(e.Infraestructura_de_cruce_peatonal_en_la_interseccion(app.b1));
            e7=cell2mat(e.Vallas_peatonales(app.b1));
            e8=cell2mat(e.Acera_lado_del_conductor(app.b1));
            e9=cell2mat(e.Acera_lado_del_copiloto(app.b1));
            e10=cell2mat(e.Infraestructura_para_motocicletas(app.b1));
            e11=cell2mat(e.Infraestructura_para_bicicletas(app.b1));
            e12=cell2mat(e.Advertencia_de_zona_escolar(app.b1));
            e13=cell2mat(e.Supervisor_en_el_cruce_de_zona_escolar(app.b1));
            app.Infraestructura={'Uso de suelo (lado del conductor)',e1;'Uso de suelo (lado del copiloto)',e2;'Tipo de área',e3;'Infraestructura de cruce peatonal en el tramo analizado',e4;'Calidad de cruce peatonal',e5;'Infraestructura de cruce peatonal en la intersección',e6;'Vallas peatonales',e7;'Acera (lado del conductor)',e8;'Acera (lado del copiloto)',e9;'Infraestructura para motocicletas',e10;'Infraestructura para bicicletas',e11;'Advertencia de zona escolar',e12;'Supervisor en el cruce de zona escolar',e13};
            app.UITable_59.Data=app.Infraestructura;
            
            f=app.Datos_interfaz_2;
            f1=cell2mat(f.Limite_de_velocidad(app.b1));
            f2=cell2mat(f.Diferencial_de_limites_de_velocidad(app.b1));
            f3=cell2mat(f.Gestion_de_la_velocidad_calmante_del_trafico(app.b1));
            f4=cell2mat(f.Velocidad_de_operacion_percentil_85(app.b1));
            app.Velocidades={'Límite de velocidad',f1;'Diferencial de límites de velocidad ',f2;'Gestión de la velocidad/calmante del tráfico',f3;'Velocidad de operación (percentil 85)',f4};
            app.UITable_60.Data=app.Velocidades;
        end
        
        function results = Suavizado(app)
            load('../mat/Datos_interfaz_dos_proceso.mat')
            Intervalo=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            Tramos=[Datos_interfaz_dos(1:Intervalo).Tramos];
            Distancia=[Datos_interfaz_dos(1:Intervalo).Distancia];
            %Xt=([Datos_interfaz_dos(1:Intervalo).Xt]);
            %yt=([Datos_interfaz_dos(1:Intervalo).yt]);
            Datos_suavizado.Tramos=Tramos;
            Datos_suavizado.Distancia=Distancia;
            %Datos_suavizado.Xt=Xt;
            %Datos_suavizado.yt=yt;
            
            %Para ocupante del vehiculo
            %Datos iniciales
            OV1=[Datos_interfaz_dos(1:Intervalo).Puntuacion_Ocupante_del_vehiculo];
            OV=cumsum([Datos_interfaz_dos(1:Intervalo).Distancia]);
            
            %Suavizacion - Media Movil
            OV2=movmean(OV1,7);
          
            %Suavizacion - Exponencial simple
            n=length(OV2);
            d=OV2;
            alfa=0.1;
            p(1)= d(1);
            for i= 1: n;
                p(i+1)= d(i)+ alfa*( d(i)-p(i));
            end;
            OV3=p(2:end);
            
            %Nuevo # de estrellas
            n=length(OV3);
            EstVehicul=OV3;
            
            for i=1:n    
            if EstVehicul(i)>=22.5
                EstVehiculo(i)=1;
            else if EstVehicul(i)>=12.5&EstVehicul(i)<22.5
                EstVehiculo(i)=2;
            else if EstVehicul(i)>=5&EstVehicul(i)<12.5
                EstVehiculo(i)=3;
            else if EstVehicul(i)>=2.5&EstVehicul(i)<5
                EstVehiculo(i)=4;
            else EstVehicul(i)>=0&EstVehicul(i)<2.5
                EstVehiculo(i)=5;
                end
                end
                end
            end
            end 
            EstVehiculo(i)
            Datos_suavizado.Puntuacion_Ocupante_del_vehiculo=EstVehicul;
            Datos_suavizado.Ocupante_del_vehiculo=EstVehiculo;
            
            %Para Motociclista
            %Datos iniciales
            M1=[Datos_interfaz_dos(1:Intervalo).Puntuacion_Motociclista];
            M=cumsum([Datos_interfaz_dos(1:Intervalo).Distancia]);
             
            %Suavizacion - Media Movil
            M2=movmean(M1,7);
            
            %Suavizacion - Exponencial simple
            n=length(M2);
            d=M2;
            alfa=0.1;
            p(1)= d(1);
            for i= 1: n;
                p(i+1)= d(i)+ alfa*( d(i)-p(i));
            end;
            M3=p(2:end);
             
            %Nuevo # de estrellas
            n=length(M3);
            EstMoto=M3;
            
            for i=1:n  
            if EstMoto(i)>=22.5
                EstMotocicleta(i)=1;
            else if EstMoto(i)>=12.5&EstMoto(i)<22.5
                EstMotocicleta(i)=2;
            else if EstMoto(i)>=5&EstMoto(i)<12.5
                EstMotocicleta(i)=3;
            else if EstMoto(i)>=2.5&EstMoto(i)<5
                EstMotocicleta(i)=4;
            else if EstMoto(i)>0.00001&EstMoto(i)<2.5
                EstMotocicleta(i)=5;
            else if EstMoto(i)==0
                EstMotocicleta(i)=0;
                end
                end
                end
                end
                end
            end
            end 
            EstMotocicleta(i)
            Datos_suavizado.Puntuacion_Motociclista=EstMoto;
            Datos_suavizado.Motociclista=EstMotocicleta;
             
            %Para Ciclista
            %Datos iniciales
            C1=[Datos_interfaz_dos(1:Intervalo).Puntuacion_Ciclista];
            C=cumsum([Datos_interfaz_dos(1:Intervalo).Distancia]);
            
            %Suavizacion - Media Movil
            C2=movmean(C1,7);
           
            %Suavizacion - Exponencial simple
            n=length(C2);
            d=C2;
            alfa=0.1;
            p(1)= d(1);
            for i= 1: n;
                p(i+1)= d(i)+ alfa*( d(i)-p(i));
            end;
            C3=p(2:end);
            
            %Nuevo # de estrellas
            n=length(C3);
            EstCicl=C3;
            
            for i=1:n  
            if EstCicl(i)>=60
                EstCiclista(i)=1
            else if EstCicl(i)>=30&EstCicl(i)<60
                EstCiclista(i)=2
            else if EstCicl(i)>=10&EstCicl(i)<30
                EstCiclista(i)=3;
            else if EstCicl(i)>=5&EstCicl(i)<10
                EstCiclista(i)=4
            else if EstCicl(i)>0.00001&EstCicl(i)<5
                EstCiclista(i)=5
            else if EstCicl(i)==0
                EstCiclista(i)=0;
                end
                end
                end
                end
                end
            end
            end 
            EstCiclista(i)
            Datos_suavizado.Puntuacion_Ciclista=EstCicl;
            Datos_suavizado.Ciclista=EstCiclista;
            
            %Para Peatones
            %Datos iniciales
            P1=[Datos_interfaz_dos(1:Intervalo).Puntuacion_Peaton];
            P=cumsum([Datos_interfaz_dos(1:Intervalo).Distancia]);
            
            %Suavizacion - Media Movil
            P2=movmean(P1,7);
            
            %Suavizacion - Exponencial simple
            n=length(P2);
            d=P2;
            alfa=0.1;
            p(1)= d(1);
            for i= 1: n;
                p(i+1)= d(i)+ alfa*( d(i)-p(i));
            end;
            P3=p(2:end);
            
            %Nuevo # de estrellas
            n=length(P3);
            EstPeato=P3;
            
            for i=1:n  
            if EstPeato(i)>=90
                EstPeaton(i)=1;
            else if EstPeato(i)>=40&EstPeato(i)<90
                EstPeaton(i)=2;
            else if EstPeato(i)>=15&EstPeato(i)<40
                EstPeaton(i)=3;
            else if EstPeato(i)>=5&EstPeato(i)<15
                EstPeaton(i)=4;
            else if EstPeato(i)>0&EstPeato(i)<5
                EstPeaton(i)=5;
                else if EstPeato(i)==0
                EstPeaton(i)=0;
                end
                end
                end
                end
                end
            end
            end 
            EstPeaton(i)
            Datos_suavizado.Puntuacion_Peaton=EstPeato;
            Datos_suavizado.Peaton=EstPeaton;
            
            save('../mat/Datos_suavizado_postproceso','Datos_suavizado')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Se cargan los datos obtenidos de las interfaces previas
            app.Datos_video=load('../mat/preproceso_datos_video.mat');
            load('../mat/Datos_interfaz_dos_proceso.mat');
            
            Intervalo=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            
            Nuevos_datos(Intervalo).Nueva_Estrella_Ocupante_vehiculo=[]
            Nuevos_datos(Intervalo).Nueva_Estrella_Motociclista=[]
            Nuevos_datos(Intervalo).Nueva_Estrella_Ciclista=[]
            Nuevos_datos(Intervalo).Nueva_Estrella_Peaton=[]
            Nuevos_datos(Intervalo).Nuevo_Puntaje_Ocupante_vehiculo=[]
            Nuevos_datos(Intervalo).Nuevo_Puntaje_Motociclista=[]
            Nuevos_datos(Intervalo).Nuevo_Puntaje_Ciclista=[]
            Nuevos_datos(Intervalo).Nuevo_Puntaje_Peaton=[]
            
            save('../mat/Nueva_clasificación_postproceso',"Nuevos_datos")
            
            Matriz_x(Intervalo).Atributos=[]
            save('../mat/Matriz_indices_nuevos_postproceso','Matriz_x')
            %Correr la función que realiza el suavizado
            Suavizado(app)
            
            app.Datos_interfaz_2=struct2table(Datos_interfaz_dos,'AsArray',true);
                       
            % Primera parte de la interfaz - Reporte General
                %   COSTADO DE LA VÍA
             %Se usa la función para extraer los elementos del primer atributo vial
            [d1,c1,d2,c2,d3,c3,d4,c4,d5,c5,d6,c6,d7,c7,d8,c8,d9,c9,d10,c10,d11,c11,d12,c12,d13,c13,d14,c14,d15,c15,d16,c16,d17,c17,d18,c18,d19,c19,d20,c20,d21,c21,d22,c22,d23,c23,d24,c24,d25,c25,d26,c26,d27,c27,d28,c28,d29,c29,d30,c30,d31,c31,d32,c32,d33,c33,d34,c34,d35,c35,d36,c36,d37,c37,d38,c38,d39,c39,d40,c40,d41,c41,d42,c42,d43,c43,d44,c44,d45,c45,d46,c46,d47,c47,d48,c48,d49,c49,d50,c50,d51,c51,d52,c52] = leer_tablas(app.Datos_interfaz_2);
            
                %Primer subatributo - Severidad lateral - objeto (lado del conductor)     
            datos={'Barrera de seguridad – metal',c5,d5;'Barrera de seguridad – hormigón',c6,d6;'Barrera de seguridad – amigable para motocicletas',c7,d7;'Barrera de seguridad – cable de acero',c8,d8;'Cara vertical agresiva en el talud en corte',c9,d9;'Pendiente ascendente del talud en corte (15° a 75°)',c10,d10;'Pendiente empinada del talud en corte (> 75°)',c11,d11;'Cuneta profunda',c12,d12;'Terraplén',c13,d13;'Precipicio',c14,d14;'Árbol (≥ 10 cm de diámetro)',c15,d15;'Letrero / poste / barra rígidos (≥ 10 cm de diámetro)',c16,d16;'Estructura / puente / edificio rígidos',c17,d17;'Estructura /edificio colapsable',c18,d18;'Extremo de barrera desprotegido',c19,d19;'Grandes rocas (≥ 20 cm de altura)',c20,d20;'Ninguno (u objeto > 20 m de la carretera)',c21,d21};
            app.UITable.Data=datos;
            app.UITable.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Severidad lateral - distancia al objeto (lado del conductor)               
            datos={'0 a < 1 m',c1,d1;'1 a < 5 m',c2,d2;'5 a < 10 m',c3,d3;'≥ 10 m',c4,d4};
            app.UITable_2.Data=datos;
            app.UITable_2.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Severidad lateral - objeto (lado del copiloto)         
            datos={'Barrera de seguridad – metal',c26,d26;'Barrera de seguridad – hormigón',c27,d27;'Barrera de seguridad – amigable para motocicletas',c28,d28;'Barrera de seguridad – cable de acero',c29,d29;'Cara vertical agresiva en el talud en corte',c30,d30;'Pendiente ascendente del talud en corte (15° a 75°)',c31,d31;'Pendiente empinada del talud en corte (> 75°)',c32,d32;'Cuneta profunda',c33,d33;'Terraplén',c34,d34;'Precipicio',c35,d35;'Árbol (≥ 10 cm de diámetro)',c36,d36;'Letrero / poste / barra rígidos (≥ 10 cm de diámetro)',c37,d37;'Estructura / puente / edificio rígidos',c38,d38;'Estructura /edificio colapsable',c39,d39;'Extremo de barrera desprotegido',c40,d40;'Grandes rocas (≥ 20 cm de altura)',c41,d41;'Ninguno (u objeto > 20 m de la carretera)',c42,d42};
            app.UITable_3.Data=datos;
            app.UITable_3.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Severidad lateral - distancia al objeto (lado del copiloto)
            datos={'0 a < 1 m',c22,d22;'1 a < 5 m',c23,d23;'5 a < 10 m',c24,d24;'≥ 10 m',c25,d25};
            app.UITable_4.Data=datos;
            app.UITable_4.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Ancho de espaldón pavimentado (lado del copiloto)
            datos={'Ancho (≥ 2,4 m)',c49,d49;'Medio (≥ 1,0 m a 2,4 m)',c50,d50;'Estrecho (≥ 0 m a 1,0 m)',c51,d51;'Ninguno',c52,d52};
            app.UITable_5.Data=datos;
            app.UITable_5.ColumnFormat={'','bank','bank'};
                %Sexto subatributo - Ancho de espaldón pavimentado (lado del conductor)
            datos={'Ancho (≥ 2,4 m)',c45,d45;'Medio (≥ 1,0 m a 2,4 m)',c46,d46;'Estrecho (≥ 0 m a 1,0 m)',c47,d47;'Ninguno',c48,d48};
            app.UITable_6.Data=datos;
            app.UITable_6.ColumnFormat={'','bank','bank'};
                %Séptimo subatributo - Bandas sonoras en el espaldón
            datos={'Ausente',c43,d43;'Presente',c44,d44};
            app.UITable_7.Data=datos;
            app.UITable_7.ColumnFormat={'','bank','bank'};
            
             %   MEDIANA
             %Se usa la función para extraer los elementos del segundo atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h5,i5,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h11,i11,h12,i12,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33,h34,i34,h35,i35,h36,i36,h37,i37,h38,i38,h39,i39,h40,i40,h41,i41,h42,i42,h43,i43,h44,i44,h45,i45,h46,i46,h47,i47,h48,i48,h49,i49,h50,i50,h51,i51,h52,i52,h53,i53,h54,i54,h55,i55,h56,i56,h57,i57,h58,i58,h59,i59,h60,i60,h61,i61,h62,i62,h63,i63,h64,i64,h65,i65,h66,i66] = leer_tablas_1(app.Datos_interfaz_2);
            
                %Primer subatributo - Tipo de carretera
            datos={'Calzada A de una calzada dividida',h1,i1;'Calzada B de una calzada dividida',h2,i2;'Carretera no dividida',h3,i3;'Calzada A de una infraestructura de motocicletas',h4,i4;'Calzada B de una infraestructura de motocicletas',h5,i5};
            app.UITable_10.Data=datos;
            app.UITable_10.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Costo de modernización
            datos={'Bajo',h6,i6;'Medio',h7,i7;'Alto',h8,i8};
            app.UITable_11.Data=datos;
            app.UITable_11.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Tipo de mediana
            datos={'Barrera de seguridad – metal',h9,i9;'Barrera de seguridad – concreto',h10,i10;'Mediana física con un ancho ≥ 20 m',h11,i11;'Mediana física con un ancho ≥ 10 m a < 20 m',h12,i12;'Mediana física con un ancho ≥ 5 m a < 10 m',h13,i13;'Mediana física con un ancho ≥ 1 m a < 5 m',h14,i14;'Mediana física con un ancho ≥ 0 m a < 1 m',h15,i15;'Carril central continuo para giro',h16,i16;'Delineadores flexibles',h17,i17;'Raya separadora de sentidos de circulación doble > 1 m y rayas diagonales a 45°',h18,i18;'Línea central',h19,i19;'Barrera de seguridad - amigable para motociclistas',h20,i20;'Un solo sentido',h21,i21;'Raya separadora de sentidos de circulación doble (0,3 m a 1 m) y rayas diagonales a 45°',h22,i22;'Barrera de seguridad - cable de acero',h23,i23};
            app.UITable_12.Data=datos;
            app.UITable_12.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Bandas sonoras centrales
            datos={'Ausente',h24,i24;'Presente',h25,i25};
            app.UITable_13.Data=datos;
            app.UITable_13.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Número de carriles
            datos={'Uno',h26,i26;'Dos',h27,i27;'Tres',h28,i28;'Cuatro o más',h29,i29;'Dos + uno',h30,i30;'Tres + dos',h31,i31};
            app.UITable_14.Data=datos;
            app.UITable_14.ColumnFormat={'','bank','bank'};
                %Sexto subatributo - Ancho de carril
            datos={'Ancho (≥ 3,25 m)',h32,i32;'Medio (≥ 2,75 m a < 3,25 m)',h33,i33;'Estrecho (≥ 0 m a < 2,75 m)',h34,i34};
            app.UITable_15.Data=datos;
            app.UITable_15.ColumnFormat={'','bank','bank'};
                %Séptimo subatributo - Curvatura
            datos={'Recto o ligeramente curvado',h35,i35;'Moderada',h36,i36;'Cerrada',h37,i37;'Muy cerrada',h38,i38};
            app.UITable_16.Data=datos;
            app.UITable_16.ColumnFormat={'','bank','bank'};
                %Octavo subatributo
            datos={'Adecuada',h39,i39;'Deficiente',h40,i40;'No aplica',h41,i41};
            app.UITable_17.Data=datos;
            app.UITable_17.ColumnFormat={'','bank','bank'};
                %Noveno subatributo - Pendiente
            datos={'0 % a < 7,5 %',h42,i42;'7,5 % a 10 %',h43,i43;'≥ 10 %',h44,i44};
            app.UITable_18.Data=datos;
            app.UITable_18.ColumnFormat={'','bank','bank'};
                %Décimo subatributo - Estado de la superficie de la vía
            datos={'Buena',h45,i45;'Regular',h46,i46;'Mala',h47,i47};
            app.UITable_19.Data=datos;
            app.UITable_19.ColumnFormat={'','bank','bank'};
                %Décimo primero subatributo - Resistencia al deslizamiento
            datos={'Pavimentado – adecuado',h48,i48;'Pavimentado – regular',h49,i49;'Pavimentado – pobre',h50,i50;'Sin pavimentar – adecuado',h51,i51;'Sin pavimentar – pobre',h52,i52};
            app.UITable_20.Data=datos;
            app.UITable_20.ColumnFormat={'','bank','bank'};
                %Décimo segundo subatributo - Delineación
            datos={'Adecuada',h53,i53;'Deficiente',h54,i54};
            app.UITable_21.Data=datos;
            app.UITable_21.ColumnFormat={'','bank','bank'};
                %Décimo tercero subatributo - Alumbrado público
            datos={'Ausente',h55,i55;'Presente',h56,i56};
            app.UITable_22.Data=datos;
            app.UITable_22.ColumnFormat={'','bank','bank'};
                %Décimo cuarto subatributo - Estacionamiento para vehículos
            datos={'Ninguno',h57,i57;'Un lado',h58,i58;'Dos lados',h59,i59};
            app.UITable_23.Data=datos;
            app.UITable_23.ColumnFormat={'','bank','bank'};
                %Décimo quinto subatributo - Vía de servicio
            datos={'Ausente',h60,i60;'Presente',h61,i61};
            app.UITable_24.Data=datos;
            app.UITable_24.ColumnFormat={'','bank','bank'};
                %Décimo sexto subatributo - Obras en la carretera
            datos={'Sin obras',h62,i62;'Obra menor en proceso',h63,i63;'Obra mayor en proceso',h64,i64};
            app.UITable_25.Data=datos;
            app.UITable_25.ColumnFormat={'','bank','bank'};
                %Décimo séptimo subatributo - Distancia de visibilidad
            datos={'Adecuada',h65,i65;'Deficiente',h66,i66};
            app.UITable_26.Data=datos;
            app.UITable_26.ColumnFormat={'','bank','bank'};
            
            %   INTERSECCIONES
            %Se usa la función para extraer los elementos del tercer atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h5,i5,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h12,i12,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33] = leer_tablas_2(app.Datos_interfaz_2);
            
                %Primer subatributo - Tipo de intersección
            datos={'Carril de incorporación',h1,i1;'Redondel',h2,i2;'3 ramas (no semaforizada) con carril de giro protegido',h3,i3;'3 ramas (no semaforizada) con carril de giro no protegido',h4,i4;'3 ramas (semaforizada) con carril de giro protegido',h5,i5;'3 ramas (semaforizada) con carril de giro no protegido',h6,i6;'4 ramas (no semaforizada) con carril de giro protegido',h7,i7;'4 ramas (no semaforizada) con carril de giro no protegido',h8,i8;'4 ramas (semaforizada) con carril de giro protegido',h9,i9;'4 ramas (semaforizada) con carril de giro no protegido',h10,i10;'Ninguno',h12,i12;'Cruce de ferrocarril - pasivo (solo señalización vertical)',h13,i13;'Cruce de ferrocarril - activo (semáforo intermitente / pluma)',h14,i14;'Punto de cruce de mediana - informal',h15,i15;'Punto de cruce de mediana - formal',h16,i16;'Mini redondel',h17,i17};
            app.UITable_28.Data=datos;
            app.UITable_28.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Canalización de la intersección
            datos={'Ausente',h18,i18;'Presente',h19,i19};
            app.UITable_27.Data=datos;
            app.UITable_27.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Volumen de la intersección
            datos={'≥ 15000 vehículos',h20,i20;'10000 a 15000 vehículos',h21,i21;'5000 a 10000 vehículos',h22,i22;'1000 a 5000 vehículos',h23,i23;'100 a 1000 vehículos',h24,i24;'1 a 100 vehículos',h25,i25;'Ninguno',h26,i26};
            app.UITable_30.Data=datos;
            app.UITable_30.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Calidad de la intersección
            datos={'Adecuada',h27,i27;'Deficiente',h28,i28;'No aplica',h29,i29};
            app.UITable_29.Data=datos;
            app.UITable_29.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Puntos de acceso a la propiedad
            datos={'Acceso comercial 1+',h30,i30;'Acceso residencial 3+',h31,i31;'Acceso residencial 1 o 2',h32,i32;'Ninguno',h33,i33};
            app.UITable_31.Data=datos;
            app.UITable_31.ColumnFormat={'','bank','bank'};
            
            %   FLUJO
            %Se usa la función para extraer los elementos del cuarto atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h5,i5,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h11,i11,h12,i12,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33,h34,i34,h35,i35,h36,i36,h37,i37,h38,i38,h39,i39,h40,i40,h41,i41,h42,i42,h43,i43,h44,i44,h45,i45,h46,i46,h47,i47,h48,i48,h49,i49,h50,i50,h51,i51,h52,i52,h53,i53,h54,i54,h55,i55,h56,i56,h57,i57,h58,i58,h59,i59,h60,i60,h61,i61,h62,i62,h63,i63,h64,i64] = leer_tablas_3(app.Datos_interfaz_2);
            
                %Primer subatributo - Flujo vehicular (TDPA)
            datos={'0 - 1000',h1,i1;'1000 - 5000',h2,i2;'5000 - 10000',h3,i3;'10000 - 15000',h4,i4;'15000 - 20000',h5,i5;'20000 - 40000',h6,i6;'40000 - 60000',h7,i7;'60000 - 80000',h8,i8;'80000 - 100000',h9,i9;'100000+',h10,i10};
            app.UITable_33.Data=datos;
            app.UITable_33.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - % motocicletas
            datos={'No registrado',h11,i11;'0 %',h12,i12;'1 % - 5 %',h13,i13;'6 % - 10 %',h14,i14;'11 % - 20 %',h15,i15;'21 % - 40 %',h16,i16;'41 % - 60 %',h17,i17;'61 % - 80 %',h18,i18;'81 % - 99 %',h19,i19;'100 %',h20,i20};
            app.UITable_32.Data=datos;
            app.UITable_32.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Flujo peatonal en hora pico que cruza la carretera
            datos={'0',h21,i21;'1 a 5',h22,i22;'6 a 25',h23,i23;'26 a 50',h24,i24;'51 a 100',h25,i25;'101 a 200',h26,i26;'201 a 300',h27,i27;'301 a 400',h28,i28;'401 a 500',h29,i29;'501 a 900',h30,i30;'900+',h31,i31};
            app.UITable_35.Data=datos;
            app.UITable_35.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Flujo peatonal en hora pico a lo largo de la carretera (lado del conductor)
            datos={'0',h32,i32;'1 a 5',h33,i33;'6 a 25',h34,i34;'26 a 50',h35,i35;'51 a 100',h36,i36;'101 a 200',h37,i37;'201 a 300',h38,i38;'301 a 400',h39,i39;'401 a 500',h40,i40;'501 a 900',h41,i41;'900+',h42,i42};
            app.UITable_34.Data=datos;
            app.UITable_34.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Flujo peatonal en hora pico a lo largo de la carretera (lado del copiloto)
            datos={'0',h43,i43;'1 a 5',h44,i44;'6 a 25',h45,i45;'26 a 50',h46,i46;'51 a 100',h47,i47;'101 a 200',h48,i48;'201 a 300',h49,i49;'301 a 400',h50,i50;'401 a 500',h51,i51;'501 a 900',h52,i52;'900+',h53,i53};
            app.UITable_36.Data=datos;
            app.UITable_36.ColumnFormat={'','bank','bank'};
                %Sexto subatributo - Flujo de bicicletas en hora pico
            datos={'Ninguno',h54,i54;'1 a 5',h55,i55;'6 a 25',h56,i56;'26 a 50',h57,i57;'51 a 100',h58,i58;'101 a 200',h59,i59;'201 a 300',h60,i60;'301 a 400',h61,i61;'401 a 500',h62,i62;'501 a 900',h63,i63;'900+',h64,i64};
            app.UITable_37.Data=datos;
            app.UITable_37.ColumnFormat={'','bank','bank'};
            
            %   INFRAESTRUCTURA PARA USUARIOS VULNERABLES Y USO DEL SUELO
            %Se usa la función para extraer los elementos del quinto atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h11,i11,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33,h34,i34,h35,i35,h36,i36,h37,i37,h38,i38,h39,i39,h40,i40,h41,i41,h43,i43,h44,i44,h45,i45,h46,i46,h47,i47,h48,i48,h49,i49,h50,i50,h51,i51,h52,i52,h53,i53,h54,i54,h55,i55,h56,i56,h57,i57,h58,i58,h59,i59,h60,i60,h61,i61,h62,i62,h63,i63,h64,i64,h65,i65,h66,i66,h67,i67,h68,i68,h69,i69,h70,i70,h71,i71,h72,i72,h73,i73,h74,i74,h75,i75,h76,i76,h77,i77,h78,i78] = leer_tablas_4(app.Datos_interfaz_2);
            
                %Primer subatributo - Uso de suelo (lado del conductor)
            datos={'Áreas no desarrolladas',h1,i1;'Agricultura y ganadería',h2,i2;'Residencial',h3,i3;'Comercial',h4,i4;'Educacional',h6,i6;'Industrial y manufactura',h7,i7};
            app.UITable_38.Data=datos;
            app.UITable_38.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Uso de suelo (lado del copiloto)
            datos={'Áreas no desarrolladas',h8,i8;'Agricultura y ganadería',h9,i9;'Residencial',h10,i10;'Comercial',h11,i11;'Educacional',h13,i13;'Industrial y manufactura',h14,i14};
            app.UITable_39.Data=datos;
            app.UITable_39.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Tipo de área
            datos={'Rural/área abierta',h15,i15;'Urbano/ población pequeña o pueblo',h16,i16};
            app.UITable_40.Data=datos;
            app.UITable_40.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Infraestructura de cruce peatonal en el tramo analizado
            datos={'Infraestructura a desnivel',h17,i17;'Semaforizada con refugio',h18,i18;'Semaforizada sin refugio',h19,i19;'No semaforizada, señalizada con pintura y con refugio',h20,i20;'No semaforizada, señalizada con pintura y sin refugio',h21,i21;'Solo refugio',h22,i22;'Sin infraestructura',h23,i23;'Cruce demarcado elevado, sin semáforo, con refugio',h24,i24;'Cruce demarcado elevado, sin semáforo, sin refugio',h25,i25;'Cruce no demarcado elevado, con refugio',h26,i26;'Cruce no demarcado elevado, sin refugio',h27,i27};
            app.UITable_41.Data=datos;
            app.UITable_41.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Calidad de cruce peatonal
            datos={'Adecuado',h28,i28;'Pobre',h29,i29;'No aplica',h30,i30};
            app.UITable_42.Data=datos;
            app.UITable_42.ColumnFormat={'','bank','bank'};
                %Sexto subatributo - Infraestructura de cruce peatonal en la intersección
            datos={'Infraestructura a desnivel',h31,i31;'Semaforizada con refugio',h32,i32;'Semaforizada sin refugio',h33,i33;'No semaforizada, señalizada con pintura y con refugio',h34,i34;'No semaforizada, señalizada con pintura y sin refugio',h35,i35;'Solo refugio',h36,i36;'Sin infraestructura',h37,i37;'Cruce demarcado elevado, sin semáforo, con refugio',h38,i38;'Cruce demarcado elevado, sin semáforo, sin refugio',h39,i39;'Cruce no demarcado elevado, con refugio',h40,i40;'Cruce no demarcado elevado, sin refugio',h41,i41};
            app.UITable_43.Data=datos;
            app.UITable_43.ColumnFormat={'','bank','bank'};
                %Séptimo subatributo - Vallas peatonales
            datos={'Presente',h43,i43;'Ausente',h44,i44};
            app.UITable_44.Data=datos;
            app.UITable_44.ColumnFormat={'','bank','bank'};
                %Octavo subatributo - Acera (lado del conductor)
            datos={'Barrera física',h45,i45;'Separación no física ≥ 3,0 m',h46,i46;'Separación no física 1,0 m a 3,0 m',h47,i47;'Separación no física 0 m < 1,0 m',h48,i48;'Ninguno',h49,i49;'Camino informal ≥ 1,0 m',h50,i50;'Camino informal de 0 m a < 1,0 m',h51,i51};
            app.UITable_45.Data=datos;
            app.UITable_45.ColumnFormat={'','bank','bank'};
                %Noveno subatributo - Acera (lado del copiloto)
            datos={'Barrera física',h52,i52;'Separación no física ≥ 3,0 m',h53,i53;'Separación no física 1,0 m a 3,0 m',h54,i54;'Separación no física 0 m < 1,0 m',h55,i55;'Ninguno',h56,i56;'Camino informal ≥ 1,0 m',h57,i57;'Camino informal de 0 m a < 1,0 m',h58,i58};
            app.UITable_46.Data=datos;
            app.UITable_46.ColumnFormat={'','bank','bank'};
                %Décimo subatributo - Infraestructura para motocicletas
            datos={'Motovía exclusiva de un sentido con barrera',h59,i59;'Motovía exclusiva de un sentido sin barrera',h60,i60;'Motovía exclusiva de dos sentidos con barrera',h61,i61;'Motovía exclusiva de dos sentidos sin barrera',h62,i62;'Motovía compartida en la carretera',h63,i63;'Ninguno',h64,i64};
            app.UITable_47.Data=datos;
            app.UITable_47.ColumnFormat={'','bank','bank'};
                %Décimo primero subatributo - Infraestructura para bicicletas
            datos={'Ciclovía fuera de la carretera con barrera',h65,i65;'Ciclovía fuera de la carretera sin barrera',h66,i66;'Ciclovía en la carretera',h67,i67;'Ninguno',h68,i68;'Carril extra fuera de la calzada (≥ 4,2 m)',h69,i69;'Vía compartida con señalización vertical',h70,i70;'Ciclovía de uso compartido ',h71,i71};
            app.UITable_48.Data=datos;
            app.UITable_48.ColumnFormat={'','bank','bank'};
                %Décimo segundo subatributo - Advertencia de zona escolar
            datos={'Balizas intermitentes en zona escolar',h72,i72;'Señalización estática o demarcación horizontal en zona escolar',h73,i73;'Sin señal de advertencia de zona escolar',h74,i74;'No aplica (no hay escuela en el lugar)',h75,i75};
            app.UITable_49.Data=datos;
            app.UITable_49.ColumnFormat={'','bank','bank'};
                %Décimo tercero subatributo - Supervisor en el cruce de zona escolar
            datos={'Supervisor de cruce en zona escolar presente en horario de entrada y salida de la escuela',h76,i76;'Supervisor de cruce en zona escolar no presente',h77,i77;'No aplica (no hay escuela en el lugar)',h78,i78};
            app.UITable_50.Data=datos;
            app.UITable_50.ColumnFormat={'','bank','bank'};
            
            %   VELOCIDADES
            %Se usa la función para extraer los elementos del sexto atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h5,i5,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h11,i11,h12,i12,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33,h34,i34,h35,i35,h36,i36,h37,i37,h38,i38,h39,i39,h40,i40,h41,i41,h42,i42,h43,i43,h44,i44,h45,i45,h46,i46,h47,i47,h48,i48,h49,i49,h50,i50,h51,i51,h52,i52,h53,i53,h54,i54] = leer_tablas_5(app.Datos_interfaz_2);
            
                %Primer subatributo - Límite de velocidad
            datos={'< 30 km/h',h1,i1;'35 km/h',h2,i2;'40 km/h',h3,i3;'45 km/h',h4,i4;'50 km/h',h5,i5;'55 km/h',h6,i6;'60 km/h',h7,i7;'65 km/h',h8,i8;'70 km/h',h9,i9;'75 km/h',h10,i10;'80 km/h',h11,i11;'85 km/h',h12,i12;'90 km/h',h13,i13;'95 km/h',h14,i14;'100 km/h',h15,i15;'105 km/h',h16,i16;'110 km/h',h17,i17;'115 km/h',h18,i18;'120 km/h',h19,i19;'125 km/h',h20,i20;'130 km/h',h21,i21;'135 km/h',h22,i22;'140 km/h',h23,i23;'145 km/h',h24,i24;' ≥ 150 km/h',h25,i25};
            app.UITable_52.Data=datos;
            app.UITable_52.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Diferencial de límites de velocidad 
            datos={'Ausente',h26,i26;'Presente',h27,i27};
            app.UITable_51.Data=datos;
            app.UITable_51.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Gestión de la velocidad/calmante del tráfico
            datos={'Ausente',h28,i28;'Presente',h29,i29};
            app.UITable_54.Data=datos;
            app.UITable_54.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Velocidad de operación (percentil 85)
            datos={'< 30 km/h',h30,i30;'35 km/h',h31,i31;'40 km/h',h32,i32;'45 km/h',h33,i33;'50 km/h',h34,i34;'55 km/h',h35,i35;'60 km/h',h36,i36;'65 km/h',h37,i37;'70 km/h',h38,i38;'75 km/h',h39,i39;'80 km/h',h40,i40;'85 km/h',h41,i41;'90 km/h',h42,i42;'95 km/h',h43,i43;'100 km/h',h44,i44;'105 km/h',h45,i45;'110 km/h',h46,i46;'115 km/h',h47,i47;'120 km/h',h48,i48;'125 km/h',h49,i49;'130 km/h',h50,i50;'135 km/h',h51,i51;'140 km/h',h52,i52;'145 km/h',h53,i53;' ≥ 150 km/h',h54,i54};
            app.UITable_53.Data=datos;
            app.UITable_53.ColumnFormat={'','bank','bank'};
            
            % Segunda parte de la interfaz - Reporte Detallado
            
                % Estilo de la tabla de la clasificación por estrellas 
            app.s1=uistyle('BackgroundColor',[0, 0.5, 0]);
            addStyle(app.UITable_9,app.s1,'cell',[1,1]);
            app.s2=uistyle('BackgroundColor',[1,1,0]);
            addStyle(app.UITable_9,app.s2,'cell',[2,1]);
            app.s3=uistyle('BackgroundColor',[0.9290, 0.6940, 0.1250]);
            addStyle(app.UITable_9,app.s3,'cell',[3,1]);
            app.s4=uistyle('BackgroundColor',[1,0,0]);
            addStyle(app.UITable_9,app.s4,'cell',[4,1]);
            app.s5=uistyle('BackgroundColor',[0,0,0]);
            addStyle(app.UITable_9,app.s5,'cell',[5,1]);
            app.s6=uistyle('BackgroundColor',[0.85 0.85 0.85]);
            addStyle(app.UITable_9,app.s6,'cell',[6,1]);
            app.s7=uistyle('FontColor',[1,1,1]);
            addStyle(app.UITable_9,app.s7,'cell',[5,1]);
            
            app.UITable_9.ColumnFormat={'','bank','bank'};

                % Valores para el Ocupante del vehículo en la
                    % tabla clasificaión por estrellas que se presentar al
                        % inicial la aplicación 
            [D,P,D01,P01,D02,P02,D03,P03,D1,P1,D2,P2,D3,P3,D4,P4,D5,P5,D6,P6,D7,P7,D8,P8,D9,P9,D10,P10,D11,P11,D12,P12,D13,P13,D14,P14,D15,P15,D16,P16,D17,P17,D18,P18,D19,P19,D20,P20]=Usuarios(app.Datos_interfaz_2); 
            Distancia_Total=(D+D1+D2+D3+D4+D5);
            Porcentaje_Total=(P+P1+P2+P3+P4+P5);
            app.Tabla_estrellas_Ocupante={'5 Estrellas',D5,P5;'4 Estrellas',D4,P4;'3 Estrellas',D3,P3;'2 Estrellas',D2,P2;'1 Estrella',D1,P1;'No aplica',D,P;'Totales',Distancia_Total,Porcentaje_Total};
            app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
            plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
            
                % Valores iniciales para la gráfica 'Mapa'
            c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            y=app.Datos_video.vbo.channels(3).data;
            x=app.Datos_video.vbo.channels(4).data;
            plot(app.UIAxes,x,y,'b');
            hold(app.UIAxes,"on");
           
            for i=1:c1
            if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0    
            plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
            hold(app.UIAxes,"on");
            elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1    
            plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
            hold(app.UIAxes,"on");
            elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
            plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
            hold(app.UIAxes,"on");
            elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
            plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
            hold(app.UIAxes,"on");
            elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
            plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
            hold(app.UIAxes,"on");
            elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
            plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
            hold(app.UIAxes,"on");
            end
            end 
            
            % Tercera parte de la interfaz - Medidas de mejoramiento
            %Contramedidas
            app.UITable2.Data=datos;
            Contramedidas=readtable('Ejemplo.xlsx');
            app.UITable2.Data=Contramedidas;
            
            
        end

        % Selection changed function: UsuariosvialesButtonGroup
        function UsuariosvialesButtonGroupSelectionChanged(app, event)
            %Para el recorrido (Mapa)
            load('../mat/Datos_interfaz_dos_proceso.mat');
            c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            
            %Y=load('ejemplo3.mat'); 
            [D,P,D01,P01,D02,P02,D03,P03,D1,P1,D2,P2,D3,P3,D4,P4,D5,P5,D6,P6,D7,P7,D8,P8,D9,P9,D10,P10,D11,P11,D12,P12,D13,P13,D14,P14,D15,P15,D16,P16,D17,P17,D18,P18,D19,P19,D20,P20] = Usuarios(app.Datos_interfaz_2);
            %Ocupante del vehículo
            Distancia_Total=(D+D1+D2+D3+D4+D5);
            Porcentaje_Total=(P+P1+P2+P3+P4+P5);
            app.Tabla_estrellas_Ocupante={'5 Estrellas',D5,P5;'4 Estrellas',D4,P4;'3 Estrellas',D3,P3;'2 Estrellas',D2,P2;'1 Estrella',D1,P1;'No aplica',D,P;'Totales',Distancia_Total,Porcentaje_Total;};

            %Motociclista
            Distancia_Total_1=(D01+D6+D7+D8+D9+D10);
            Porcentaje_Total_1=(P01+P6+P7+P8+P9+P10);
            app.Tabla_estrellas_Motociclista={'5 Estrellas',D10,P10;'4 Estrellas',D9,P9;'3 Estrellas',D8,P8;'2 Estrellas',D7,P7;'1 Estrella',D6,P6;'No aplica',D01,P01;'Totales',Distancia_Total_1,Porcentaje_Total_1;};

            %Ciclista
            Distancia_Total_2=(D02+D11+D12+D13+D14+D15);
            Porcentaje_Total_2=(P02+P11+P12+P13+P14+P15);
            app.Tabla_estrellas_Cilista={'5 Estrellas',D15,P15;'4 Estrellas',D14,P14;'3 Estrellas',D13,P13;'2 Estrellas',D12,P12;'1 Estrella',D11,P11;'No aplica',D02,P02;'Totales',Distancia_Total_2,Porcentaje_Total_2;};

            %Peatón
            Distancia_Total_3=(D03+D16+D17+D18+D19+D20);
            Porcentaje_Total_3=(P03+P16+P17+P18+P19+P20);
            app.Tabla_estrellas_Peaton={'5 Estrellas',D20,P20;'4 Estrellas',D19,P19;'3 Estrellas',D18,P18;'2 Estrellas',D17,P17;'1 Estrella',D16,P16;'No aplica',D03,P03;'Totales',Distancia_Total_3,Porcentaje_Total_3;};       
     
            %selectedButton = app.UsuariosvialesButtonGroup.SelectedObject;
            V=app.OcupantedevehculosButton.Value;
            M=app.MotociclistaButton.Value;
            C=app.CiclistaButton.Value;
            P=app.PeatnButton.Value;
            if V==1
                app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
                for i=1:c1
                if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
                end
            elseif M==1
                app.UITable_9.Data=app.Tabla_estrellas_Motociclista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista);
                for i=1:c1
                if Datos_interfaz_dos(i).Motociclista ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
                end
            elseif C==1
                app.UITable_9.Data=app.Tabla_estrellas_Cilista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista);
                for i=1:c1
                if Datos_interfaz_dos(i).Ciclista ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
                end
            elseif P==1
                app.UITable_9.Data=app.Tabla_estrellas_Peaton; 
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton);
                for i=1:c1
                if Datos_interfaz_dos(i).Peaton ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
                end
            end
        end

        % Button pushed function: PDFButton_3
        function PDFButton_3Pushed(app, event)

            UsuariosvialesButtonGroupSelectionChanged(app)
            load('../mat/Datos_interfaz_dos_proceso.mat');
            c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            load('../mat/preproceso_datos_video.mat');
            y=vbo.channels(3).data;
            x=vbo.channels(4).data;

            % Reporte 
            import mlreportgen.report.*
            import mlreportgen.dom.*
            rpt = Report(' Reporte Final', 'pdf');
            
             %Portada
            titlepg = TitlePage;
            titlepg.Title = 'CARRETERAS: CLASIFICACIÓN POR ESTRELLAS';
            titlepg.Subtitle = 'Reporte detallado';
            titlepg.Author = 'UNIVERSIDAD TÉCNICA PARTICULAR DE LOJA-SISTEMA DE EVALUACIÓN DE SEGURIDAD VIAL';
            add(rpt,titlepg)
            
            %Tabla de contenidos
            add(rpt,TableOfContents);
            
            %Capítulos
            %Descargo de responsabilidad
            chap0 = Chapter('Descargo de responsabilidad');
            add(chap0,Paragraph('Este descargo de responsabilidad es un acuerdo entre la UTPL y usted el usuario.'));
            add(chap0,Paragraph('El software SIEC y toda la documentación relacionada, son y deben ser siempre propiedad intelectual de la UTPL. Nada de lo contenido en este acuerdo debe ser considerado por el usuario para darle título de propiedad al software o de la documentación relacionada. '));
            add(chap0,Paragraph('Este software fue desarrollado para analizar el nivel de seguridad vial de las carreteras rurales del Ecuador. Incluye dos funcionalidades principales. La primera funcionalidad hace uso del archivo VBOX donde los radios de las curvas horizontales son aproximaciones del diseño horizontal, dado que se obtuvieron con el uso de los datos de posición del Video VBOX Lite. Así mismo, la pendiente fue calculada mediante los datos del perfil del mismo equipo utilizando el promedio de dos métodos: método a y método b. El ancho del carril, espaldón y la separación de objetos de la calzada fueron calculados a partir de una imagen del video del mismo archivo. La velocidad de operación fue calculada en base a las ecuaciones de predicción locales. El resto de elementos han sido ingresados de manera manual por el usuario. La segunda funcionalidad hace uso sólo de fotografías, en la cual se eliminará lo que no corresponde con el Video VBOX Lite. El usuario debe comprender explícitamente los supuestos de la metodología, análisis y resultados obtenidos mediante el software. La información producida por el software debe ser comprobada por un ingeniero calificado y con experiencia. El ingeniero debe verificar independientemente los resultados y tomar profesional responsabilidad por la información que se utiliza.'));
            add(chap0,Paragraph('Este programa ha sido probado y verificado exhaustivamente, sin embargo, como ocurre con todo software complejo, es posible que estos programas no estén completamente libres de errores y que no sean aplicables en todos los casos. El usuario acepta y entiende que no hay garantía expresa o implícitas por los desarrolladores o los distribuidores sobre la precisión o la fiabilidad de este producto. En ningún caso la Universidad Técnica Particular de Loja. Será responsable por daños directos, indirectos, especiales, incidentales o consecuentes que surjan del uso del programa y / o documentación asociada. Además, la reproducción y difusión del material generado por el software son solo para fines educativos e informativos, los fines comerciales sin el permiso por escrito de los titulares de los derechos de autor está prohibida. '));
            add(chap0,Paragraph('Si no está satisfecho con alguna parte del software SIEC o con alguno de estos términos de uso, su único y exclusivo recurso es dejar de usar el programa.'));
            add(rpt,chap0);
            
            
            %Capítulo 1
            chap = Chapter('Información del archivo');
            add(chap,Paragraph('Nombre del proyecto:'));
            add(chap,Paragraph('Longitud total:' )); 
            add(chap,Paragraph('Creado por:')); 
            add(chap,Paragraph('Fecha:')); 
            add(chap,Paragraph('Nombre de archivo: ')); 
            add(chap,Paragraph('Directorio'));
            add(rpt,chap);
            
            
            %Capítulo 2
            chap2 = Chapter('Clasificación por estrellas de la carretera');
          
            %Estilo de tabla establecido
            tableStyle = { ...
                Width('100%'), ...
                Border('solid','black','1px'), ...
                ColSep('solid','black','1px'), ...
                RowSep('solid','black','1px') ...
                };
            
            %Ocupante de vehículo
            add(chap2,Paragraph('Datos para el ocupante de vehículo'))
            add(chap2,Paragraph('Tabla de resumen'));
            add(chap2,Paragraph('En la siguiente tabla se indica la clasificación por estrellas para Ocupante del vehículo '))
            Tablax=Table(app.Tabla_estrellas_Ocupante);
            Tablax.TableEntriesHAlign = 'center';
            Tablax.Style = tableStyle;
            add(chap2,Tablax);
            
            add(chap2,Paragraph('Gráfica'));
            add(chap2,Paragraph('A continuación, se muestra en la figura, los Puntajes de clasificación por estrellas de cada sección del tramo en análisis '))
            fig1 = figure('visible','off');
            ax = axes(fig1,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo,'-b','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (Km)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Distancia - Puntaje de clasificación por estrellas");
            add(chap2,Figure(fig1))
            
            add(chap2,Paragraph('Mapa'));
            add(chap2,Paragraph('A continuación, se muestra el mapa del recorrido '))

            fig = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Mapa");
            hold on
            for i=1:c1 
                if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                end
            end
            hold off
            
            add(chap2,Figure(fig));
            
            %Motociclista
            add(chap2,Paragraph('Datos para el motociclista'))
            add(chap2,Paragraph('Tabla de resumen'));
            add(chap2,Paragraph('En la siguiente tabla se indica la clasificación por estrellas para los motociclistas '))
            Tablay=Table(app.Tabla_estrellas_Motociclista);
            Tablay.TableEntriesHAlign = 'center';
            Tablay.Style = tableStyle;
            add(chap2,Tablay);
            
            add(chap2,Paragraph('Gráfica'));
            add(chap2,Paragraph('A continuación, se muestra en la figura, los Puntajes de clasificación por estrellas de cada sección del tramo en análisis '))
            fig2 = figure('visible','off');
            ax = axes(fig2,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista,'-b','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (Km)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Distancia - Puntaje de clasificación por estrellas");
            add(chap2,Figure(fig2))
            
            add(chap2,Paragraph('Mapa'));
            add(chap2,Paragraph('A continuación, se muestra el mapa del recorrido '))

            fig3 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Mapa");
            hold on
            for i=1:c1 
                if Datos_interfaz_dos(i).Motociclista ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                elseif Datos_interfaz_dos(i).Motociclista ==1 
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                elseif Datos_interfaz_dos(i).Motociclista ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                elseif Datos_interfaz_dos(i).Motociclista ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                elseif Datos_interfaz_dos(i).Motociclista ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                elseif Datos_interfaz_dos(i).Motociclista ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                end
            end
            hold off
            
            add(chap2,Figure(fig3));
            
            %Ciclista
            add(chap2,Paragraph('Datos para el Ciclista'))
            add(chap2,Paragraph('Tabla de resumen'));
            add(chap2,Paragraph('En la siguiente tabla se indica la clasificación por estrellas para los Ciclistas '))
            Tablaz=Table(app.Tabla_estrellas_Cilista);
            Tablaz.TableEntriesHAlign = 'center';
            Tablaz.Style = tableStyle;
            add(chap2,Tablaz);
            
            add(chap2,Paragraph('Gráfica'));
            add(chap2,Paragraph('A continuación, se muestra en la figura, los Puntajes de clasificación por estrellas de cada sección del tramo en análisis '))
            fig4 = figure('visible','off');
            ax = axes(fig4,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista,'-b','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (Km)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Distancia - Puntaje de clasificación por estrellas");
            add(chap2,Figure(fig4))
            
            add(chap2,Paragraph('Mapa'));
            add(chap2,Paragraph('A continuación, se muestra el mapa del recorrido '))

            fig5 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Mapa");
            hold on
            for i=1:c1 
                if Datos_interfaz_dos(i).Ciclista ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                elseif Datos_interfaz_dos(i).Ciclista ==1 
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                elseif Datos_interfaz_dos(i).Ciclista ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                elseif Datos_interfaz_dos(i).Ciclista ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                elseif Datos_interfaz_dos(i).Ciclista ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                elseif Datos_interfaz_dos(i).Ciclista ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                end
            end
            hold off
            
            add(chap2,Figure(fig5));
            
            
            %Peatón
            add(chap2,Paragraph('Datos para el Peatón'))
            add(chap2,Paragraph('Tabla de resumen'));
            add(chap2,Paragraph('En la siguiente tabla se indica la clasificación por estrellas para los Peatones '))
            Tablaa=Table(app.Tabla_estrellas_Peaton);
            Tablaa.TableEntriesHAlign = 'center';
            Tablaa.Style = tableStyle;
            add(chap2,Tablaa);
            
            add(chap2,Paragraph('Gráfica'));
            add(chap2,Paragraph('A continuación, se muestra en la figura, los Puntajes de clasificación por estrellas de cada sección del tramo en análisis '))
            fig6 = figure('visible','off');
            ax = axes(fig6,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton,'-b','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (Km)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Distancia - Puntaje de clasificación por estrellas");
            add(chap2,Figure(fig6))
            
            add(chap2,Paragraph('Mapa'));
            add(chap2,Paragraph('A continuación, se muestra el mapa del recorrido '))

            fig7 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Mapa");
            hold on
            for i=1:c1 
                if Datos_interfaz_dos(i).Peaton ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                elseif Datos_interfaz_dos(i).Peaton ==1 
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                elseif Datos_interfaz_dos(i).Peaton ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                elseif Datos_interfaz_dos(i).Peaton ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                elseif Datos_interfaz_dos(i).Peaton ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                elseif Datos_interfaz_dos(i).Peaton ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                end
            end
            hold off
            
            add(chap2,Figure(fig7));
            
            
            add(rpt,chap2);
            
            %Capítulo 3
            chap3 = Chapter('Condiciones detalladas de la carretera');
            add(chap3,Paragraph('Costado de la vía'))
            
            Tabla2=Table(app.UITable_2.Data);
            Tabla2.TableEntriesHAlign = 'center';
            Tabla2.Style = tableStyle;
            add(chap3,Tabla2);
            add(chap3,Paragraph(''))
            
            Tabla1=Table(app.UITable.Data);
            Tabla1.TableEntriesHAlign = 'center';
            Tabla1.Style = tableStyle;
            add(chap3,Tabla1);
            add(chap3,Paragraph(''))
            
            Tabla4=Table(app.UITable_4.Data);
            Tabla4.TableEntriesHAlign = 'center';
            Tabla4.Style = tableStyle;
            add(chap3,Tabla4);
            add(chap3,Paragraph(''))
            
            Tabla3=Table(app.UITable_3.Data);
            Tabla3.TableEntriesHAlign = 'center';
            Tabla3.Style = tableStyle;
            add(chap3,Tabla3);
            add(chap3,Paragraph(''))
            
            Tabla7=Table(app.UITable_7.Data);
            Tabla7.TableEntriesHAlign = 'center';
            Tabla7.Style = tableStyle;
            add(chap3,Tabla7);
            add(chap3,Paragraph(''))
            
            Tabla6=Table(app.UITable_6.Data);
            Tabla6.TableEntriesHAlign = 'center';
            Tabla6.Style = tableStyle;
            add(chap3,Tabla6);
            add(chap3,Paragraph(''))
            
            Tabla5=Table(app.UITable_5.Data);
            Tabla5.TableEntriesHAlign = 'center';
            Tabla5.Style = tableStyle;
            add(chap3,Tabla5);
            add(chap3,Paragraph(''))
           
            add(chap3,Paragraph('Mediana'))
            
            Tabla10=Table(app.UITable_10.Data);
            Tabla10.TableEntriesHAlign = 'center';
            Tabla10.Style = tableStyle;
            add(chap3,Tabla10);
            add(chap3,Paragraph(''))
            
            Tabla11=Table(app.UITable_11.Data);
            Tabla11.TableEntriesHAlign = 'center';
            Tabla11.Style = tableStyle;
            add(chap3,Tabla11);
            add(chap3,Paragraph(''))
            
            Tabla12=Table(app.UITable_12.Data);
            Tabla12.TableEntriesHAlign = 'center';
            Tabla12.Style = tableStyle;
            add(chap3,Tabla12);
            add(chap3,Paragraph(''))
            
            Tabla13=Table(app.UITable_13.Data);
            Tabla13.TableEntriesHAlign = 'center';
            Tabla13.Style = tableStyle;
            add(chap3,Tabla13);
            add(chap3,Paragraph(''))
            
            Tabla14=Table(app.UITable_14.Data);
            Tabla14.TableEntriesHAlign = 'center';
            Tabla14.Style = tableStyle;
            add(chap3,Tabla14);
            add(chap3,Paragraph(''))
            
            Tabla15=Table(app.UITable_15.Data);
            Tabla15.TableEntriesHAlign = 'center';
            Tabla15.Style = tableStyle;
            add(chap3,Tabla15);
            add(chap3,Paragraph(''))
            
            Tabla16=Table(app.UITable_16.Data);
            Tabla16.TableEntriesHAlign = 'center';
            Tabla16.Style = tableStyle;
            add(chap3,Tabla16);
            add(chap3,Paragraph(''))
            
            Tabla17=Table(app.UITable_17.Data);
            Tabla17.TableEntriesHAlign = 'center';
            Tabla17.Style = tableStyle;
            add(chap3,Tabla17);
            add(chap3,Paragraph(''))
            
            Tabla18=Table(app.UITable_18.Data);
            Tabla18.TableEntriesHAlign = 'center';
            Tabla18.Style = tableStyle;
            add(chap3,Tabla18);
            add(chap3,Paragraph(''))
            
            Tabla19=Table(app.UITable_19.Data);
            Tabla19.TableEntriesHAlign = 'center';
            Tabla19.Style = tableStyle;
            add(chap3,Tabla19);
            add(chap3,Paragraph(''))
            
            Tabla20=Table(app.UITable_20.Data);
            Tabla20.TableEntriesHAlign = 'center';
            Tabla20.Style = tableStyle;
            add(chap3,Tabla20);
            add(chap3,Paragraph(''))
            
            Tabla21=Table(app.UITable_21.Data);
            Tabla21.TableEntriesHAlign = 'center';
            Tabla21.Style = tableStyle;
            add(chap3,Tabla21);
            add(chap3,Paragraph(''))
            
            Tabla22=Table(app.UITable_22.Data);
            Tabla22.TableEntriesHAlign = 'center';
            Tabla22.Style = tableStyle;
            add(chap3,Tabla22);
            add(chap3,Paragraph(''))
            
            Tabla23=Table(app.UITable_23.Data);
            Tabla23.TableEntriesHAlign = 'center';
            Tabla23.Style = tableStyle;
            add(chap3,Tabla23);
            add(chap3,Paragraph(''))
            
            Tabla24=Table(app.UITable_24.Data);
            Tabla24.TableEntriesHAlign = 'center';
            Tabla24.Style = tableStyle;
            add(chap3,Tabla24);
            add(chap3,Paragraph(''))
            
            Tabla25=Table(app.UITable_25.Data);
            Tabla25.TableEntriesHAlign = 'center';
            Tabla25.Style = tableStyle;
            add(chap3,Tabla25);
            add(chap3,Paragraph(''))
            
            Tabla26=Table(app.UITable_26.Data);
            Tabla26.TableEntriesHAlign = 'center';
            Tabla26.Style = tableStyle;
            add(chap3,Tabla26);
            add(chap3,Paragraph(''))
            
            add(chap3,Paragraph('Intersecciones'))
            
            Tabla28=Table(app.UITable_28.Data);
            Tabla28.TableEntriesHAlign = 'center';
            Tabla28.Style = tableStyle;
            add(chap3,Tabla28);
            add(chap3,Paragraph(''))
            
            Tabla27=Table(app.UITable_27.Data);
            Tabla27.TableEntriesHAlign = 'center';
            Tabla27.Style = tableStyle;
            add(chap3,Tabla27);
            add(chap3,Paragraph(''))
            
            Tabla30=Table(app.UITable_30.Data);
            Tabla30.TableEntriesHAlign = 'center';
            Tabla30.Style = tableStyle;
            add(chap3,Tabla30);
            add(chap3,Paragraph(''))
            
            Tabla29=Table(app.UITable_29.Data);
            Tabla29.TableEntriesHAlign = 'center';
            Tabla29.Style = tableStyle;
            add(chap3,Tabla29);
            add(chap3,Paragraph(''))
            
            Tabla31=Table(app.UITable_31.Data);
            Tabla31.TableEntriesHAlign = 'center';
            Tabla31.Style = tableStyle;
            add(chap3,Tabla31);
            add(chap3,Paragraph(''))
            
            add(chap3,Paragraph('Flujo'))
            
            Tabla33=Table(app.UITable_33.Data);
            Tabla33.TableEntriesHAlign = 'center';
            Tabla33.Style = tableStyle;
            add(chap3,Tabla33);
            add(chap3,Paragraph(''))
            
            Tabla32=Table(app.UITable_32.Data);
            Tabla32.TableEntriesHAlign = 'center';
            Tabla32.Style = tableStyle;
            add(chap3,Tabla32);
            add(chap3,Paragraph(''))
            
            Tabla35=Table(app.UITable_35.Data);
            Tabla35.TableEntriesHAlign = 'center';
            Tabla35.Style = tableStyle;
            add(chap3,Tabla35);
            add(chap3,Paragraph(''))
            
            Tabla34=Table(app.UITable_34.Data);
            Tabla34.TableEntriesHAlign = 'center';
            Tabla34.Style = tableStyle;
            add(chap3,Tabla34);
            add(chap3,Paragraph(''))
            
            Tabla36=Table(app.UITable_36.Data);
            Tabla36.TableEntriesHAlign = 'center';
            Tabla36.Style = tableStyle;
            add(chap3,Tabla36);
            add(chap3,Paragraph(''))
            
            Tabla37=Table(app.UITable_37.Data);
            Tabla37.TableEntriesHAlign = 'center';
            Tabla37.Style = tableStyle;
            add(chap3,Tabla37);
            add(chap3,Paragraph(''))
            
            add(chap3,Paragraph('Infraestructura para usuarios vulnerables y uso del suelo'))
            
            Tabla38=Table(app.UITable_38.Data);
            Tabla38.TableEntriesHAlign = 'center';
            Tabla38.Style = tableStyle;
            add(chap3,Tabla38);
            add(chap3,Paragraph(''))
            
            Tabla39=Table(app.UITable_39.Data);
            Tabla39.TableEntriesHAlign = 'center';
            Tabla39.Style = tableStyle;
            add(chap3,Tabla39);
            add(chap3,Paragraph(''))
            
            Tabla40=Table(app.UITable_40.Data);
            Tabla40.TableEntriesHAlign = 'center';
            Tabla40.Style = tableStyle;
            add(chap3,Tabla40);
            add(chap3,Paragraph(''))
            
            Tabla41=Table(app.UITable_41.Data);
            Tabla41.TableEntriesHAlign = 'center';
            Tabla41.Style = tableStyle;
            add(chap3,Tabla41);
            add(chap3,Paragraph(''))
            
            Tabla42=Table(app.UITable_42.Data);
            Tabla42.TableEntriesHAlign = 'center';
            Tabla42.Style = tableStyle;
            add(chap3,Tabla42);
            add(chap3,Paragraph(''))
            
            Tabla43=Table(app.UITable_43.Data);
            Tabla43.TableEntriesHAlign = 'center';
            Tabla43.Style = tableStyle;
            add(chap3,Tabla43);
            add(chap3,Paragraph(''))
            
            Tabla44=Table(app.UITable_44.Data);
            Tabla44.TableEntriesHAlign = 'center';
            Tabla44.Style = tableStyle;
            add(chap3,Tabla44);
            add(chap3,Paragraph(''))
            
            Tabla45=Table(app.UITable_45.Data);
            Tabla45.TableEntriesHAlign = 'center';
            Tabla45.Style = tableStyle;
            add(chap3,Tabla45);
            add(chap3,Paragraph(''))
            
            Tabla46=Table(app.UITable_46.Data);
            Tabla46.TableEntriesHAlign = 'center';
            Tabla46.Style = tableStyle;
            add(chap3,Tabla46);
            add(chap3,Paragraph(''))
            
            Tabla47=Table(app.UITable_47.Data);
            Tabla47.TableEntriesHAlign = 'center';
            Tabla47.Style = tableStyle;
            add(chap3,Tabla47);
            add(chap3,Paragraph(''))
            
            Tabla48=Table(app.UITable_48.Data);
            Tabla48.TableEntriesHAlign = 'center';
            Tabla48.Style = tableStyle;
            add(chap3,Tabla48);
            add(chap3,Paragraph(''))
            
            Tabla49=Table(app.UITable_49.Data);
            Tabla49.TableEntriesHAlign = 'center';
            Tabla49.Style = tableStyle;
            add(chap3,Tabla49);
            add(chap3,Paragraph(''))
            
            Tabla50=Table(app.UITable_50.Data);
            Tabla50.TableEntriesHAlign = 'center';
            Tabla50.Style = tableStyle;
            add(chap3,Tabla50);
            add(chap3,Paragraph(''))
            
            add(chap3,Paragraph('Velocidades'))
            
            Tabla52=Table(app.UITable_52.Data);
            Tabla52.TableEntriesHAlign = 'center';
            Tabla52.Style = tableStyle;
            add(chap3,Tabla52);
            add(chap3,Paragraph(''))
            
            Tabla51=Table(app.UITable_51.Data);
            Tabla51.TableEntriesHAlign = 'center';
            Tabla51.Style = tableStyle;
            add(chap3,Tabla51);
            add(chap3,Paragraph(''))
            
            Tabla54=Table(app.UITable_54.Data);
            Tabla54.TableEntriesHAlign = 'center';
            Tabla54.Style = tableStyle;
            add(chap3,Tabla54);
            add(chap3,Paragraph(''))
            
            Tabla53=Table(app.UITable_53.Data);
            Tabla53.TableEntriesHAlign = 'center';
            Tabla53.Style = tableStyle;
            add(chap3,Tabla53);
            add(chap3,Paragraph(''))
            
            add(rpt,chap3);
            
           
            close(rpt);
            rptview(rpt);
        end

        % Value changed function: TramosguardadosSpinner
        function TramosguardadosSpinnerValueChanged(app, event)
            B=app.Datos_interfaz_2.Tramos;
            app.TramosguardadosSpinner.Limits=[1 length(B)];
            app.b1 = app.TramosguardadosSpinner.Value;
            buscar(app);
            buscar_1(app)
            
            %Revisar si se puede utilizar sino eliminar
            %x=cell2mat(app.Datos_interfaz_2.Curvatura(1));
            %if x=='Recto o ligeramente curvado'
             %  app.UITable2.Data{3,5}==1
            %end
            
            
        end

        % Value changed function: SuavizadoCheckBox
        function SuavizadoCheckBoxValueChanged(app, event)
            load('../mat/Datos_suavizado_postproceso.mat')
            load('../mat/Datos_interfaz_dos_proceso.mat');
            c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            
            [D,P,D01,P01,D02,P02,D03,P03,D1,P1,D2,P2,D3,P3,D4,P4,D5,P5,D6,P6,D7,P7,D8,P8,D9,P9,D10,P10,D11,P11,D12,P12,D13,P13,D14,P14,D15,P15,D16,P16,D17,P17,D18,P18,D19,P19,D20,P20] = Usuarios(Datos_suavizado);
            %Ocupante del vehículo
            Distancia_Total=(D+D1+D2+D3+D4+D5);
            Porcentaje_Total=(P+P1+P2+P3+P4+P5);
            app.Tabla_Nuevaestrellas_Ocupante={'5 Estrellas',D5,P5;'4 Estrellas',D4,P4;'3 Estrellas',D3,P3;'2 Estrellas',D2,P2;'1 Estrella',D1,P1;'No aplica',D,P;'Totales',Distancia_Total,Porcentaje_Total;};

            %Motociclista
            Distancia_Total_1=(D01+D6+D7+D8+D9+D10);
            Porcentaje_Total_1=(P01+P6+P7+P8+P9+P10);
            app.Tabla_Nuevaestrellas_Motociclista={'5 Estrellas',D10,P10;'4 Estrellas',D9,P9;'3 Estrellas',D8,P8;'2 Estrellas',D7,P7;'1 Estrella',D6,P6;'No aplica',D01,P01;'Totales',Distancia_Total_1,Porcentaje_Total_1;};

            %Ciclista
            Distancia_Total_2=(D02+D11+D12+D13+D14+D15);
            Porcentaje_Total_2=(P02+P11+P12+P13+P14+P15);
            app.Tabla_Nuevaestrellas_Cilista={'5 Estrellas',D15,P15;'4 Estrellas',D14,P14;'3 Estrellas',D13,P13;'2 Estrellas',D12,P12;'1 Estrella',D11,P11;'No aplica',D02,P02;'Totales',Distancia_Total_2,Porcentaje_Total_2;};

            %Peatón
            Distancia_Total_3=(D03+D16+D17+D18+D19+D20);
            Porcentaje_Total_3=(P03+P16+P17+P18+P19+P20);
            app.Tabla_Nuevaestrellas_Peaton={'5 Estrellas',D20,P20;'4 Estrellas',D19,P19;'3 Estrellas',D18,P18;'2 Estrellas',D17,P17;'1 Estrella',D16,P16;'No aplica',D03,P03;'Totales',Distancia_Total_3,Porcentaje_Total_3;};       

            value = app.SuavizadoCheckBox.Value;
            if value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Ocupante;
                   plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ocupante_del_vehiculo);
                   
                   for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                    end
                   
                   if app.OcupantedevehculosButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Ocupante;
                   plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ocupante_del_vehiculo);
                   for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end
                   end
                   if app.MotociclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Motociclista;
                   plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista);
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Motociclista);
                   for i=1:length(Datos_suavizado.Motociclista)
                    if Datos_suavizado.Motociclista(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                    end
                   
                   end
                   if app.CiclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Cilista;
                   plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista);
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ciclista);
                   for i=1:length(Datos_suavizado.Ciclista)
                    if Datos_suavizado.Ciclista(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                    end
                           
                   end
                   if app.PeatnButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Peaton;
                   plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton);
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Peaton); 
                   for i=1:length(Datos_suavizado.Peaton)
                    if Datos_suavizado.Peaton(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end
                   end
            end  
            
            if value ==0
                app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
                
                for i=1:c1
                if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
                end
                
                if app.OcupantedevehculosButton.Value ==1
                   app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
                   plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
                for i=1:c1
                if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
                end   
                end
                if app.MotociclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_estrellas_Motociclista;
                   plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista);
                for i=1:c1
                if Datos_interfaz_dos(i).Motociclista ==0   
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Motociclista ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
                end
                end
                if app.CiclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_estrellas_Cilista;
                   plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista);
                for i=1:c1
                if Datos_interfaz_dos(i).Ciclista ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ciclista ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
                end
                end
                if app.PeatnButton.Value ==1
                   app.UITable_9.Data=app.Tabla_estrellas_Peaton;
                   plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton);
                for i=1:c1
                if Datos_interfaz_dos(i).Peaton ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Peaton ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
                end   
                end 
            end 
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
         load('../mat/Datos_atributos_proceso.mat')
         
         app.b1; %Este es el valor que se asumio que eligue el datos del spinner
         app.Matriz=Atributo_elegido(app.b1).Atributo;
            
            if app.UITable2.Data{1,5}==1
                   app.Matriz(16)=1; 
            end
            
            if app.UITable2.Data{2,5}==1
               app.Matriz(24)=1;
            end
            if app.UITable2.Data{3,5}==1
               app.Matriz(14)=1;
            end
            if app.UITable2.Data{4,5}==1
               app.Matriz(10)=3;
            end
            if app.UITable2.Data{5,5}==1
               app.Matriz(12)=2;
            end
            if app.UITable2.Data{6,5}==1
               app.Matriz(10)=4;
            end
            if app.UITable2.Data{7,5}==1
               app.Matriz(12)=2;
            end
            if app.UITable2.Data{8,5}==1
               app.Matriz(10)=5;
            end
            if app.UITable2.Data{9,5}==1
               app.Matriz(12)=2;
            end
            if app.UITable2.Data{10,5}==1
               app.Matriz(10)=6;
            end
            if app.UITable2.Data{11,5}==1
               app.Matriz(12)=2;
            end
            if app.UITable2.Data{12,5}==1
               app.Matriz(10)=7;
            end
            if app.UITable2.Data{13,5}==1
               app.Matriz(12)=2;  
            end
            if app.UITable2.Data{14,5}==1
               app.Matriz(10)=12;
            end
            if app.UITable2.Data{15,5}==1
               app.Matriz(12)=2;
            end
            if app.UITable2.Data{16,5}==1
               app.Matriz(29)=3;
            end
            if app.UITable2.Data{17,5}==1
               app.Matriz(22)=2;
            end
            if app.UITable2.Data{18,5}==1
               app.Matriz(10)=15;
            end
            if app.UITable2.Data{19,5}==1
               app.Matriz(12)=5;
            end
            if app.UITable2.Data{20,5}==1
               app.Matriz(10)=13;
            end
            if app.UITable2.Data{21,5}==1
               app.Matriz(12)=5;
            end
            if app.UITable2.Data{22,5}==1
               app.Matriz(25)=1;
            end
            if app.UITable2.Data{23,5}==1
               app.Matriz(10)=15;
            end
            if app.UITable2.Data{24,5}==1
               app.Matriz(10)=8;
            end
            if app.UITable2.Data{25,5}==1
               app.Matriz(10)=15;
            end
            if app.UITable2.Data{26,5}==1
               app.Matriz(10)=9;
            end
            if app.UITable2.Data{27,5}==1
               app.Matriz(10)=10;
            end
            if app.UITable2.Data{28,5}==1
               app.Matriz(10)=14;
            end
            if app.UITable2.Data{29,5}==1
               app.Matriz(45)=2;
            end
            if app.UITable2.Data{30,5}==1
               app.Matriz(45)=5;
            end
            if app.UITable2.Data{31,5}==1
               app.Matriz(45)=5;
            end
            if app.UITable2.Data{32,5}==1
               app.Matriz(13)=1;
            end
            if app.UITable2.Data{33,5}==1
               app.Matriz(13)=1;
            end
            if app.UITable2.Data{34,5}==1
               app.Matriz(7)=1;
            end
            if app.UITable2.Data{35,5}==1
               app.Matriz(7)=2;
            end
            if app.UITable2.Data{36,5}==1
               app.Matriz(6)=1;
            end
            if app.UITable2.Data{37,5}==1
               app.Matriz(6)=2;  
            end
            if app.UITable2.Data{38,5}==1
               app.Matriz(5)=2;
            end
            if app.UITable2.Data{39,5}==1
               app.Matriz(1)=3;
            end
            if app.UITable2.Data{40,5}==1
               app.Matriz(2)=3;
            end
            if app.UITable2.Data{41,5}==1
               app.Matriz(3)=3;
            end
            if app.UITable2.Data{42,5}==1
               app.Matriz(4)=3;
            end
            if app.UITable2.Data{43,5}==1
               app.Matriz(1)=4;
            end
            if app.UITable2.Data{44,5}==1
               app.Matriz(2)=11;
            end
            if app.UITable2.Data{45,5}==1
               app.Matriz(3)=4;
            end
            if app.UITable2.Data{46,5}==1
               app.Matriz(4)=11;
            end
            if app.UITable2.Data{47,5}==1
               app.Matriz(1)=4;
            end
            if app.UITable2.Data{48,5}==1
               app.Matriz(2)=9;
            end
            if app.UITable2.Data{49,5}==1
               app.Matriz(3)=4;
            end
            if app.UITable2.Data{50,5}==1
               app.Matriz(4)=9;
            end
            if app.UITable2.Data{51,5}==1
               app.Matriz(25)=2;
            end
            if app.UITable2.Data{52,5}==1
               app.Matriz(18)=2;
            end
            if app.UITable2.Data{53,5}==1
               app.Matriz(17)=1;
            end
            if app.UITable2.Data{54,5}==1
               app.Matriz(18)=1;
            end
            if app.UITable2.Data{55,5}==1
               app.Matriz(18)=4;
            end
            if app.UITable2.Data{56,5}==1
               app.Matriz(25)=9;
            end
            if app.UITable2.Data{57,5}==1
               app.Matriz(25)=9;
            end
            if app.UITable2.Data{58,5}==1
               app.Matriz(25)=7;
            end
            if app.UITable2.Data{59,5}==1
               app.Matriz(25)=5;
            end
            if app.UITable2.Data{60,5}==1
               app.Matriz(25)=5;
            end
            if app.UITable2.Data{61,5}==1
               app.Matriz(25)=3;  
            end
            if app.UITable2.Data{62,5}==1
               app.Matriz(25)=13;
            end
            if app.UITable2.Data{63,5}==1
               app.Matriz(25)=15;
            end
            if app.UITable2.Data{64,5}==1
               app.Matriz(28)=1;
            end
            if app.UITable2.Data{65,5}==1
               app.Matriz(46)=1;
            end
            if app.UITable2.Data{66,5}==1
               app.Matriz(46)=3;
            end
            if app.UITable2.Data{67,5}==1
               app.Matriz(39)=1;
            end
            if app.UITable2.Data{68,5}==1
               app.Matriz(39)=2;
            end
            if app.UITable2.Data{69,5}==1
               app.Matriz(48)=1;
            end
            if app.UITable2.Data{70,5}==1
               app.Matriz(39)=8;
            end
            if app.UITable2.Data{71,5}==1
               app.Matriz(39)=2;
            end
            if app.UITable2.Data{72,5}==1
               app.Matriz(39)=6;
            end
            if app.UITable2.Data{73,5}==1
               app.Matriz(40)=1;
            end
            if app.UITable2.Data{74,5}==1
               app.Matriz(39)=1;
            end
            if app.UITable2.Data{75,5}==1
               app.Matriz(39)=2;
            end
            if app.UITable2.Data{76,5}==1
               app.Matriz(39)=4;
            end
            if app.UITable2.Data{77,5}==1
               app.Matriz(44)=1;
            end
            if app.UITable2.Data{78,5}==1
               app.Matriz(44)=2;
            end
            if app.UITable2.Data{79,5}==1
               app.Matriz(44)=4;
            end
            if app.UITable2.Data{80,5}==1
               app.Matriz(44)=6;
            end
            if app.UITable2.Data{81,5}==1
               app.Matriz(43)=1;
            end
            if app.UITable2.Data{82,5}==1
               app.Matriz(43)=2;
            end
            if app.UITable2.Data{83,5}==1
               app.Matriz(43)=4;
            end
            if app.UITable2.Data{84,5}==1
               app.Matriz(43)=6;
            end
            if app.UITable2.Data{85,5}==1
               app.Matriz(42)=1;  
            end
            if app.UITable2.Data{86,5}==1
               app.Matriz(20)=2;
            end
            if app.UITable2.Data{87,5}==1
               app.Matriz(20)=2;
            end
            if app.UITable2.Data{88,5}==1
               app.Matriz(20)=2;
            end
            if app.UITable2.Data{89,5}==1
               app.Matriz(24)=1;
            end
            if app.UITable2.Data{90,5}==1
               app.Matriz(47)=1;
            end
            if app.UITable2.Data{91,5}==1
               app.Matriz(47)=2;
            end
            if app.UITable2.Data{92,5}==1
               app.Matriz(28)=1;
            end
            if app.UITable2.Data{93,5}==1
               app.Matriz(15)=1;
            end
            if app.UITable2.Data{94,5}==1
               app.Matriz(19)=1;
            end
            if app.UITable2.Data{95,5}==1
               app.Matriz(29)=4;
            end
            if app.UITable2.Data{96,5}==1
               app.Matriz(51)=2;
            end
            if app.UITable2.Data{97,5}==1
               app.Matriz(21)=2;
            end
            if app.UITable2.Data{98,5}==1
               app.Matriz(3)=3;
            end
            if app.UITable2.Data{99,5}==1
               app.Matriz(4)=9;
            end
            if app.UITable2.Data{100,5}==1
               app.Matriz(3)=3;
            end
            if app.UITable2.Data{101,5}==1
               app.Matriz(4)=11;
            end
            if app.UITable2.Data{102,5}==1
               app.Matriz(3)=3;
            end
            if app.UITable2.Data{103,5}==1
               app.Matriz(4)=3;
            end
            if app.UITable2.Data{104,5}==1
               app.Matriz(45)=4;
            end
            if app.UITable2.Data{105,5}==1
               app.Matriz(3)=4;
            end
            if app.UITable2.Data{106,5}==1
               app.Matriz(4)=9;
            end
            if app.UITable2.Data{107,5}==1
               app.Matriz(3)=4;
            end
            if app.UITable2.Data{108,5}==1
               app.Matriz(4)=11;
            end
            if app.UITable2.Data{109,5}==1
               app.Matriz(3)=3;
            end
            if app.UITable2.Data{110,5}==1
               app.Matriz(4)=3;
            end
            if app.UITable2.Data{111,5}==1
               app.Matriz(1)=4;
            end
            if app.UITable2.Data{112,5}==1
               app.Matriz(2)=9;
            end
            if app.UITable2.Data{113,5}==1
               app.Matriz(1)=4;
            end
            if app.UITable2.Data{114,5}==1
               app.Matriz(2)=11;
            end 
            if app.UITable2.Data{115,5}==1
               app.Matriz(1)=3;
            end
            if app.UITable2.Data{116,5}==1
               app.Matriz(2)=3;
            end
            if app.UITable2.Data{117,5}==1
               app.Matriz(52)=1;
            end
            if app.UITable2.Data{118,5}==1
               app.Matriz(49)=1; 
            end

         [EstVehiculo,EstMotocicleta,EstCiclista,EstPeaton,EstVehicul,EstMoto,EstCicl,EstPeato]=Calculo_estrellas_VehIntentoFinal(app.Matriz);
         app.OcupantedevehculosEditField_5.Value=EstVehicul;
         app.MotociclistaEditField_5.Value=EstMoto;
         app.CiclistaEditField_5.Value=EstCicl;
         app.PeatnEditField_5.Value=EstPeato;
         
         app.OcupantedevehculosEditField_6.Value=EstVehiculo;
         app.MotociclistaEditField_6.Value=EstMotocicleta;
         app.CiclistaEditField_6.Value=EstCiclista;
         app.PeatnEditField_6.Value=EstPeaton;
         
         load('../mat/Matriz_indices_nuevos_postproceso.mat')
         
         Matriz_x(app.b1).Atributos=app.Matriz;
         save('../mat/Matriz_indices_nuevos_postproceso','Matriz_x')
         
         load('../mat/Nueva_clasificación_postproceso.mat')
         Nuevos_datos(app.b1).Nueva_Estrella_Ocupante_vehiculo=app.OcupantedevehculosEditField_5.Value;
         Nuevos_datos(app.b1).Nueva_Estrella_Motociclista=app.MotociclistaEditField_5.Value;
         Nuevos_datos(app.b1).Nueva_Estrella_Ciclista=app.CiclistaEditField_5.Value;
         Nuevos_datos(app.b1).Nueva_Estrella_Peaton=app.PeatnEditField_5.Value;

         Nuevos_datos(app.b1).Nuevo_Puntaje_Ocupante_vehiculo=app.OcupantedevehculosEditField_6.Value;
         Nuevos_datos(app.b1).Nuevo_Puntaje_Motociclista=app.MotociclistaEditField_6.Value;
         Nuevos_datos(app.b1).Nuevo_Puntaje_Ciclista=app.CiclistaEditField_6.Value;
         Nuevos_datos(app.b1).Nuevo_Puntaje_Peaton=app.PeatnEditField_6.Value;
         save('../mat/Nueva_clasificación_postproceso',"Nuevos_datos")
            
        end

        % Callback function
        function UITable_55CellEdit(app, event)
            
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            
            app.OcupantedevehculosEditField_5.Value=0;
            app.MotociclistaEditField_5.Value=0;
            app.CiclistaEditField_5.Value=0;
            app.PeatnEditField_5.Value=0;
            app.OcupantedevehculosEditField_6.Value=0;
            app.MotociclistaEditField_6.Value=0;
            app.CiclistaEditField_6.Value=0;
            app.PeatnEditField_6.Value=0;
            
            %TramosguardadosSpinnerValueChanged(app)
            %app.TramosguardadosSpinner.Value=app.TramosguardadosSpinner.Value+1;
            
            app.Matriz=[];
            app.UITable2.Data{:,5}=0;
            
            
        end

        % Button pushed function: PDFButton_2
        function PDFButton_2Pushed(app, event)

            load('../mat/Datos_interfaz_dos_proceso.mat');
            c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));


            % Reporte # 2
            import mlreportgen.report.*
            import mlreportgen.dom.*
            rpt = Report(' Reporte Contramedidas', 'pdf');
            
             %Portada
            titlepg = TitlePage;
            titlepg.Title = 'CARRETERAS: CLASIFICACIÓN POR ESTRELLAS';
            titlepg.Subtitle = 'Reporte Contramedidas';
            titlepg.Author = 'UNIVERSIDAD TÉCNICA PARTICULAR DE LOJA-SISTEMA DE EVALUACIÓN DE SEGURIDAD VIAL';
            add(rpt,titlepg)
            
            %Tabla de contenidos
            add(rpt,TableOfContents);
            
            %Capítulos
            %Descargo de responsabilidad
            chap0 = Chapter('Descargo de responsabilidad');
            add(chap0,Paragraph('Este descargo de responsabilidad es un acuerdo entre la UTPL y usted el usuario.'));
            add(chap0,Paragraph('El software SIEC y toda la documentación relacionada, son y deben ser siempre propiedad intelectual de la UTPL. Nada de lo contenido en este acuerdo debe ser considerado por el usuario para darle título de propiedad al software o de la documentación relacionada. '));
            add(chap0,Paragraph('Este software fue desarrollado para analizar el nivel de seguridad vial de las carreteras rurales del Ecuador. Incluye dos funcionalidades principales. La primera funcionalidad hace uso del archivo VBOX donde los radios de las curvas horizontales son aproximaciones del diseño horizontal, dado que se obtuvieron con el uso de los datos de posición del Video VBOX Lite. Así mismo, la pendiente fue calculada mediante los datos del perfil del mismo equipo utilizando el promedio de dos métodos: método a y método b. El ancho del carril, espaldón y la separación de objetos de la calzada fueron calculados a partir de una imagen del video del mismo archivo. La velocidad de operación fue calculada en base a las ecuaciones de predicción locales. El resto de elementos han sido ingresados de manera manual por el usuario. La segunda funcionalidad hace uso sólo de fotografías, en la cual se eliminará lo que no corresponde con el Video VBOX Lite. El usuario debe comprender explícitamente los supuestos de la metodología, análisis y resultados obtenidos mediante el software. La información producida por el software debe ser comprobada por un ingeniero calificado y con experiencia. El ingeniero debe verificar independientemente los resultados y tomar profesional responsabilidad por la información que se utiliza.'));
            add(chap0,Paragraph('Este programa ha sido probado y verificado exhaustivamente, sin embargo, como ocurre con todo software complejo, es posible que estos programas no estén completamente libres de errores y que no sean aplicables en todos los casos. El usuario acepta y entiende que no hay garantía expresa o implícitas por los desarrolladores o los distribuidores sobre la precisión o la fiabilidad de este producto. En ningún caso la Universidad Técnica Particular de Loja. Será responsable por daños directos, indirectos, especiales, incidentales o consecuentes que surjan del uso del programa y / o documentación asociada. Además, la reproducción y difusión del material generado por el software son solo para fines educativos e informativos, los fines comerciales sin el permiso por escrito de los titulares de los derechos de autor está prohibida. '));
            add(chap0,Paragraph('Si no está satisfecho con alguna parte del software SIEC o con alguno de estos términos de uso, su único y exclusivo recurso es dejar de usar el programa.'));
            add(rpt,chap0);
            
            
            %Capítulo 1
            chap = Chapter('Información del archivo');
            add(chap,Paragraph('Nombre del proyecto:'));
            add(chap,Paragraph('Longitud total:' )); 
            add(chap,Paragraph('Tramo analizado:' ));
            add(chap,Paragraph('Creado por:')); 
            add(chap,Paragraph('Fecha:')); 
            add(rpt,chap);
            
            
            %Capítulo 2
            chap2 = Chapter('Condiciones iniciales de la carretera');
          
            %Estilo de tabla establecido
            tableStyle = { ...
                Width('100%'), ...
                Border('solid','black','1px'), ...
                ColSep('solid','black','1px'), ...
                RowSep('solid','black','1px') ...
                };
            
            %Sin contramedidas
            add(chap2,Paragraph('Atributos de la carretera'))
            add(chap2,Paragraph('Costado de la vía'));
            Tabla1=Table(app.UITable_55.Data);
            Tabla1.TableEntriesHAlign = 'center';
            Tabla1.Style = tableStyle;
            add(chap2,Tabla1);
            add(chap2,Paragraph(''))
            
            add(chap2,Paragraph('Mediana'));
            Tabla2=Table(app.UITable_56.Data);
            Tabla2.TableEntriesHAlign = 'center';
            Tabla2.Style = tableStyle;
            add(chap2,Tabla2);
            add(chap2,Paragraph(''))
            
            add(chap2,Paragraph('Intersecciones'));
            Tabla3=Table(app.UITable_57.Data);
            Tabla3.TableEntriesHAlign = 'center';
            Tabla3.Style = tableStyle;
            add(chap2,Tabla3);
            add(chap2,Paragraph(''))
            
            add(chap2,Paragraph('Flujo'));
            Tabla4=Table(app.UITable_58.Data);
            Tabla4.TableEntriesHAlign = 'center';
            Tabla4.Style = tableStyle;
            add(chap2,Tabla4);
            add(chap2,Paragraph(''))
            
            add(chap2,Paragraph('Infraestructura para usuarios vulnerables y uso del suelo'));
            Tabla5=Table(app.UITable_59.Data);
            Tabla5.TableEntriesHAlign = 'center';
            Tabla5.Style = tableStyle;
            add(chap2,Tabla5);
            add(chap2,Paragraph(''))
            
            add(chap2,Paragraph('Velocidades'));
            Tabla6=Table(app.UITable_60.Data);
            Tabla6.TableEntriesHAlign = 'center';
            Tabla6.Style = tableStyle;
            add(chap2,Tabla6);
            add(chap2,Paragraph(''))

           
            add(chap2,Paragraph('Puntajes de clasificación por estrellas'));
            add(chap2,Paragraph('Ocupante de vehículos'));
            add(chap2,Paragraph('Motociclista'));
            add(chap2,Paragraph('Ciclistas'));
            add(chap2,Paragraph('Peatón'));
            add(chap2,Paragraph(''))
            add(chap2,Paragraph('Clasificación por estrellas'));
            add(chap2,Paragraph('Ocupante de vehículos'));
            add(chap2,Paragraph('Motociclista'));
            add(chap2,Paragraph('Ciclistas'));
            add(chap2,Paragraph('Peatón'));
            
            add(rpt,chap2);
            
            %Capítulo 3
            chap3 = Chapter('Condiciones de la carretera aplicadas contramedidas');
         
            add(chap3,Paragraph('Contramedidas aplicadas'))
            
            %Tabla2=Table(app.UITable_2.Data);
            %Tabla2.TableEntriesHAlign = 'center';
            %Tabla2.Style = tableStyle;
            %add(chap3,Tabla2);
            %add(chap3,Paragraph(''))
            
            add(chap3,Paragraph('Nuevas condiciones'));
            add(chap3,Paragraph('Puntajes de clasificación por estrellas'));
            add(chap3,Paragraph('Ocupante de vehículos'));
            add(chap3,Paragraph('Motociclista'));
            add(chap3,Paragraph('Ciclistas'));
            add(chap3,Paragraph('Peatón'));
            add(chap3,Paragraph(''))
            add(chap3,Paragraph('Clasificación por estrellas'));
            add(chap3,Paragraph('Ocupante de vehículos'));
            add(chap3,Paragraph('Motociclista'));
            add(chap3,Paragraph('Ciclistas'));
            add(chap3,Paragraph('Peatón'));
            
            
            add(rpt,chap3);
            
           
            close(rpt);
            rptview(rpt);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Reporte = uifigure('Visible', 'off');
            app.Reporte.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.Reporte);
            app.ReporteGeneralTab = uitab(app.TabGroup2);
            app.ReporteGeneralTab.Title = 'Reporte General';
            app.TabGroup = uitabgroup(app.ReporteGeneralTab);
            app.CostadodelavaTab = uitab(app.TabGroup);
            app.CostadodelavaTab.Title = 'Costado de la vía';
            app.UITable = uitable(app.CostadodelavaTab);
            app.UITable.ColumnName = {'Severidad lateral - objeto (lado del conductor)'; 'Km'; '%'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.CostadodelavaTab);
            app.UITable_2.ColumnName = {'Severidad lateral - distancia al objeto (lado del conductor)'; 'Km'; '%'};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.CostadodelavaTab);
            app.UITable_3.ColumnName = {'Severidad lateral - objeto (lado del copiloto)'; 'Km'; '%'};
            app.UITable_3.RowName = {};
            app.UITable_4 = uitable(app.CostadodelavaTab);
            app.UITable_4.ColumnName = {'Severidad lateral - distancia al objeto (lado del copiloto)'; 'Km'; '%'};
            app.UITable_4.RowName = {};
            app.UITable_5 = uitable(app.CostadodelavaTab);
            app.UITable_5.ColumnName = {'Ancho de espaldón pavimentado (lado del copiloto)'; 'Km'; '%'};
            app.UITable_5.RowName = {};
            app.UITable_6 = uitable(app.CostadodelavaTab);
            app.UITable_6.ColumnName = {'Ancho de espaldón pavimentado (lado del conductor)'; 'Km'; '%'};
            app.UITable_6.RowName = {};
            app.UITable_7 = uitable(app.CostadodelavaTab);
            app.UITable_7.ColumnName = {'Bandas sonoras en el espaldón'; 'Km'; '%'};
            app.UITable_7.RowName = {};
            app.MedianaTab = uitab(app.TabGroup);
            app.MedianaTab.Title = 'Mediana';
            app.UITable_10 = uitable(app.MedianaTab);
            app.UITable_10.ColumnName = {'Tipo de carretera'; 'Km'; '%'};
            app.UITable_10.RowName = {};
            app.UITable_11 = uitable(app.MedianaTab);
            app.UITable_11.ColumnName = {'Costo de modernización'; 'Km'; '%'};
            app.UITable_11.RowName = {};
            app.UITable_13 = uitable(app.MedianaTab);
            app.UITable_13.ColumnName = {'Bandas sonoras centrales'; 'Km'; '%'};
            app.UITable_13.RowName = {};
            app.UITable_12 = uitable(app.MedianaTab);
            app.UITable_12.ColumnName = {'Tipo de mediana'; 'Km'; '%'};
            app.UITable_12.RowName = {};
            app.UITable_14 = uitable(app.MedianaTab);
            app.UITable_14.ColumnName = {'Número de carriles'; 'Km'; '%'};
            app.UITable_14.RowName = {};
            app.UITable_15 = uitable(app.MedianaTab);
            app.UITable_15.ColumnName = {'Ancho de carril'; 'Km'; '%'};
            app.UITable_15.RowName = {};
            app.UITable_16 = uitable(app.MedianaTab);
            app.UITable_16.ColumnName = {'Curvatura'; 'Km'; '%'};
            app.UITable_16.RowName = {};
            app.UITable_17 = uitable(app.MedianaTab);
            app.UITable_17.ColumnName = {'Calidad de la curva'; 'Km'; '%'};
            app.UITable_17.RowName = {};
            app.UITable_18 = uitable(app.MedianaTab);
            app.UITable_18.ColumnName = {'Pendiente'; 'Km'; '%'};
            app.UITable_18.RowName = {};
            app.UITable_19 = uitable(app.MedianaTab);
            app.UITable_19.ColumnName = {'Estado de la superficie de la vía'; 'Km'; '%'};
            app.UITable_19.RowName = {};
            app.UITable_20 = uitable(app.MedianaTab);
            app.UITable_20.ColumnName = {'Resistencia al deslizamiento'; 'Km'; '%'};
            app.UITable_20.RowName = {};
            app.UITable_21 = uitable(app.MedianaTab);
            app.UITable_21.ColumnName = {'Delineación'; 'Km'; '%'};
            app.UITable_21.RowName = {};
            app.UITable_22 = uitable(app.MedianaTab);
            app.UITable_22.ColumnName = {'Alumbrado público'; 'Km'; '%'};
            app.UITable_22.RowName = {};
            app.UITable_23 = uitable(app.MedianaTab);
            app.UITable_23.ColumnName = {'Estacionamiento para vehículos'; 'Km'; '%'};
            app.UITable_23.RowName = {};
            app.UITable_24 = uitable(app.MedianaTab);
            app.UITable_24.ColumnName = {'Vía de servicio'; 'Km'; '%'};
            app.UITable_24.RowName = {};
            app.UITable_25 = uitable(app.MedianaTab);
            app.UITable_25.ColumnName = {'Obras en la carretera'; 'Km'; '%'};
            app.UITable_25.RowName = {};
            app.UITable_26 = uitable(app.MedianaTab);
            app.UITable_26.ColumnName = {'Distancia de visibilidad'; 'Km'; '%'};
            app.UITable_26.RowName = {};
            app.InterseccionesTab = uitab(app.TabGroup);
            app.InterseccionesTab.Title = 'Intersecciones';
            app.UITable_27 = uitable(app.InterseccionesTab);
            app.UITable_27.ColumnName = {'Canalización de intersección'; 'Km'; '%'};
            app.UITable_27.RowName = {};
            app.UITable_28 = uitable(app.InterseccionesTab);
            app.UITable_28.ColumnName = {'Tipo de intersección'; 'Km'; '%'};
            app.UITable_28.RowName = {};
            app.UITable_29 = uitable(app.InterseccionesTab);
            app.UITable_29.ColumnName = {'Calidad de la intersección'; 'Km'; '%'};
            app.UITable_29.RowName = {};
            app.UITable_30 = uitable(app.InterseccionesTab);
            app.UITable_30.ColumnName = {'Volumen de la intersección'; 'Km'; '%'};
            app.UITable_30.RowName = {};
            app.UITable_31 = uitable(app.InterseccionesTab);
            app.UITable_31.ColumnName = {'Puntos de acceso a la propiedad'; 'Km'; '%'};
            app.UITable_31.RowName = {};
            app.FlujoTab = uitab(app.TabGroup);
            app.FlujoTab.Title = 'Flujo';
            app.UITable_32 = uitable(app.FlujoTab);
            app.UITable_32.ColumnName = {'% motocicletas'; 'Km'; '%'};
            app.UITable_32.RowName = {};
            app.UITable_33 = uitable(app.FlujoTab);
            app.UITable_33.ColumnName = {'Flujo vehicular (TDPA)'; 'Km'; '%'};
            app.UITable_33.RowName = {};
            app.UITable_34 = uitable(app.FlujoTab);
            app.UITable_34.ColumnName = {'Flujo peatonal en hora pico a lo largo de la carretera (lado del conductor)'; 'Km'; '%'};
            app.UITable_34.RowName = {};
            app.UITable_35 = uitable(app.FlujoTab);
            app.UITable_35.ColumnName = {'Flujo peatonal en hora pico que cruza la carretera'; 'Km'; '%'};
            app.UITable_35.RowName = {};
            app.UITable_36 = uitable(app.FlujoTab);
            app.UITable_36.ColumnName = {'Flujo peatonal en hora pico a lo largo de la carretera (lado del copiloto)'; 'Km'; '%'};
            app.UITable_36.RowName = {};
            app.UITable_37 = uitable(app.FlujoTab);
            app.UITable_37.ColumnName = {'Flujo de bicicletas en hora pico'; 'Km'; '%'};
            app.UITable_37.RowName = {};
            app.InfraestructuraparausuariosvulnerablesyusodelsueloTab = uitab(app.TabGroup);
            app.InfraestructuraparausuariosvulnerablesyusodelsueloTab.Title = 'Infraestructura para usuarios vulnerables y uso del suelo';
            app.UITable_38 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_38.ColumnName = {'Uso de suelo (lado del conductor)'; 'Km'; '%'};
            app.UITable_38.RowName = {};
            app.UITable_39 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_39.ColumnName = {'Uso de suelo (lado del copiloto)'; 'Km'; '%'};
            app.UITable_39.RowName = {};
            app.UITable_40 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_40.ColumnName = {'Tipo de área'; 'Km'; '%'};
            app.UITable_40.RowName = {};
            app.UITable_41 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_41.ColumnName = {'Infraestructura de cruce peatonal en el tramo analizado'; 'Km'; '%'};
            app.UITable_41.RowName = {};
            app.UITable_42 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_42.ColumnName = {' Calidad de cruce peatonal'; 'Km'; '%'};
            app.UITable_42.RowName = {};
            app.UITable_43 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_43.ColumnName = {'Infraestructura de cruce peatonal en la intersección'; 'Km'; '%'};
            app.UITable_43.RowName = {};
            app.UITable_44 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_44.ColumnName = {' Vallas peatonales '; 'Km'; '%'};
            app.UITable_44.RowName = {};
            app.UITable_45 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_45.ColumnName = {'Acera (lado del conductor)'; 'Km'; '%'};
            app.UITable_45.RowName = {};
            app.UITable_46 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_46.ColumnName = {'Acera (lado del copiloto) '; 'Km'; '%'};
            app.UITable_46.RowName = {};
            app.UITable_47 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_47.ColumnName = {'Infraestructura para motocicletas '; 'Km'; '%'};
            app.UITable_47.RowName = {};
            app.UITable_48 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_48.ColumnName = {'Infraestructura para bicicletas'; 'Km'; '%'};
            app.UITable_48.RowName = {};
            app.UITable_49 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_49.ColumnName = {'Advertencia de zona escolar'; 'Km'; '%'};
            app.UITable_49.RowName = {};
            app.UITable_50 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_50.ColumnName = {' Supervisor en el cruce de zona escolar'; 'Km'; '%'};
            app.UITable_50.RowName = {};
            app.VelocidadesTab = uitab(app.TabGroup);
            app.VelocidadesTab.Title = 'Velocidades';
            app.UITable_51 = uitable(app.VelocidadesTab);
            app.UITable_51.ColumnName = {'Diferencial de límites de velocidad '; 'Km'; '%'};
            app.UITable_51.RowName = {};
            app.UITable_52 = uitable(app.VelocidadesTab);
            app.UITable_52.ColumnName = {'Límite de velocidad'; 'Km'; '%'};
            app.UITable_52.RowName = {};
            app.UITable_53 = uitable(app.VelocidadesTab);
            app.UITable_53.ColumnName = {'Velocidad de operación (percentil 85)'; 'Km'; '%'};
            app.UITable_53.RowName = {};
            app.UITable_54 = uitable(app.VelocidadesTab);
            app.UITable_54.ColumnName = {'Gestión de la velocidad/calmante del tráfico'; 'Km'; '%'};
            app.UITable_54.RowName = {};
            app.ReportedetalladoTab = uitab(app.TabGroup2);
            app.ReportedetalladoTab.Title = 'Reporte detallado';
            app.TabGroup_2 = uitabgroup(app.ReportedetalladoTab);
            app.ClasificacindeestrellasTab = uitab(app.TabGroup_2);
            app.ClasificacindeestrellasTab.Title = 'Clasificación de estrellas';
            app.UITable_9 = uitable(app.ClasificacindeestrellasTab);
            app.UITable_9.ColumnName = {'Clasificación de estrellas'; 'Longitud (Km)'; 'Porcentaje (%)'};
            app.UITable_9.RowName = {};
            app.UsuariosvialesButtonGroup = uibuttongroup(app.ReportedetalladoTab);
            app.UsuariosvialesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UsuariosvialesButtonGroupSelectionChanged, true);
            app.UsuariosvialesButtonGroup.Title = 'Usuarios viales';
            app.OcupantedevehculosButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.OcupantedevehculosButton.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosButton.Value = true;
            app.MotociclistaButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.MotociclistaButton.Text = 'Motociclista';
            app.CiclistaButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.CiclistaButton.Text = 'Ciclista';
            app.PeatnButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.PeatnButton.Text = 'Peatón';
            app.PDFButton_3 = uibutton(app.ReportedetalladoTab, 'push');
            app.PDFButton_3.ButtonPushedFcn = createCallbackFcn(app, @PDFButton_3Pushed, true);
            app.PDFButton_3.Icon = 'icono-PDF.png';
            app.PDFButton_3.Text = 'PDF';
            app.MapaPanel = uipanel(app.ReportedetalladoTab);
            app.MapaPanel.Title = 'Mapa';
            app.UIAxes = uiaxes(app.MapaPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Longitud')
            ylabel(app.UIAxes, 'Latitud')
            app.DistanciaClasificacinporestrellasPanel = uipanel(app.ReportedetalladoTab);
            app.DistanciaClasificacinporestrellasPanel.Title = 'Distancia-Clasificación por estrellas';
            app.UIAxes_2 = uiaxes(app.DistanciaClasificacinporestrellasPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'Distancia')
            ylabel(app.UIAxes_2, 'Puntajes de clasificación por estrellas')
            app.SuavizadoCheckBox = uicheckbox(app.ReportedetalladoTab);
            app.SuavizadoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuavizadoCheckBoxValueChanged, true);
            app.SuavizadoCheckBox.Text = 'Suavizado';
            app.MedidasdemejoramientoTab = uitab(app.TabGroup2);
            app.MedidasdemejoramientoTab.Title = 'Medidas de mejoramiento';
            app.DATOSGENERALESDELACARRETERAPanel = uipanel(app.MedidasdemejoramientoTab);
            app.DATOSGENERALESDELACARRETERAPanel.Title = 'DATOS GENERALES DE LA CARRETERA';
            app.NombreTextAreaLabel = uilabel(app.DATOSGENERALESDELACARRETERAPanel);
            app.NombreTextAreaLabel.Text = 'Nombre:';
            app.NombreTextArea = uitextarea(app.DATOSGENERALESDELACARRETERAPanel);
            app.TramosguardadosSpinnerLabel = uilabel(app.DATOSGENERALESDELACARRETERAPanel);
            app.TramosguardadosSpinnerLabel.Text = 'Tramos guardados';
            app.TramosguardadosSpinner = uispinner(app.DATOSGENERALESDELACARRETERAPanel);
            app.TramosguardadosSpinner.ValueChangedFcn = createCallbackFcn(app, @TramosguardadosSpinnerValueChanged, true);
            app.ATRIBUTOSCODIFICADOSPanel = uipanel(app.MedidasdemejoramientoTab);
            app.ATRIBUTOSCODIFICADOSPanel.Title = 'ATRIBUTOS CODIFICADOS';
            app.TabGroup3 = uitabgroup(app.ATRIBUTOSCODIFICADOSPanel);
            app.CostadodelavaTab_2 = uitab(app.TabGroup3);
            app.CostadodelavaTab_2.Title = 'Costado de la vía';
            app.UITable_55 = uitable(app.CostadodelavaTab_2);
            app.UITable_55.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_55.RowName = {};
            app.MedianaTab_2 = uitab(app.TabGroup3);
            app.MedianaTab_2.Title = 'Mediana';
            app.UITable_56 = uitable(app.MedianaTab_2);
            app.UITable_56.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_56.RowName = {};
            app.InterseccionesTab_2 = uitab(app.TabGroup3);
            app.InterseccionesTab_2.Title = 'Intersecciones';
            app.UITable_57 = uitable(app.InterseccionesTab_2);
            app.UITable_57.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_57.RowName = {};
            app.FlujoTab_2 = uitab(app.TabGroup3);
            app.FlujoTab_2.Title = 'Flujo';
            app.UITable_58 = uitable(app.FlujoTab_2);
            app.UITable_58.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_58.RowName = {};
            app.InfraestructuraparausuariosvulnerablesyusodelsueloTab_2 = uitab(app.TabGroup3);
            app.InfraestructuraparausuariosvulnerablesyusodelsueloTab_2.Title = 'Infraestructura para usuarios vulnerables y uso del suelo';
            app.UITable_59 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab_2);
            app.UITable_59.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_59.RowName = {};
            app.VelocidadesTab_2 = uitab(app.TabGroup3);
            app.VelocidadesTab_2.Title = 'Velocidades';
            app.UITable_60 = uitable(app.VelocidadesTab_2);
            app.UITable_60.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_60.RowName = {};
            app.ClasificacinporestrellasPanel = uipanel(app.ATRIBUTOSCODIFICADOSPanel);
            app.ClasificacinporestrellasPanel.Title = 'Clasificación por estrellas';
            app.OcupantedevehculosEditField_3Label = uilabel(app.ClasificacinporestrellasPanel);
            app.OcupantedevehculosEditField_3Label.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosEditField_3 = uieditfield(app.ClasificacinporestrellasPanel, 'numeric');
            app.MotociclistaEditField_3Label = uilabel(app.ClasificacinporestrellasPanel);
            app.MotociclistaEditField_3Label.Text = 'Motociclista';
            app.MotociclistaEditField_3 = uieditfield(app.ClasificacinporestrellasPanel, 'numeric');
            app.CiclistaEditField_3Label = uilabel(app.ClasificacinporestrellasPanel);
            app.CiclistaEditField_3Label.Text = 'Ciclista';
            app.CiclistaEditField_3 = uieditfield(app.ClasificacinporestrellasPanel, 'numeric');
            app.PeatnEditField_3Label = uilabel(app.ClasificacinporestrellasPanel);
            app.PeatnEditField_3Label.Text = 'Peatón';
            app.PeatnEditField_3 = uieditfield(app.ClasificacinporestrellasPanel, 'numeric');
            app.PuntajedeclasificacinPanel = uipanel(app.ATRIBUTOSCODIFICADOSPanel);
            app.PuntajedeclasificacinPanel.Title = 'Puntaje de clasificación';
            app.OcupantedevehculosEditField_4Label = uilabel(app.PuntajedeclasificacinPanel);
            app.OcupantedevehculosEditField_4Label.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosEditField_4 = uieditfield(app.PuntajedeclasificacinPanel, 'numeric');
            app.CiclistaEditField_4Label = uilabel(app.PuntajedeclasificacinPanel);
            app.CiclistaEditField_4Label.Text = 'Ciclista';
            app.CiclistaEditField_4 = uieditfield(app.PuntajedeclasificacinPanel, 'numeric');
            app.MotociclistaEditField_4Label = uilabel(app.PuntajedeclasificacinPanel);
            app.MotociclistaEditField_4Label.Text = 'Motociclista';
            app.MotociclistaEditField_4 = uieditfield(app.PuntajedeclasificacinPanel, 'numeric');
            app.PeatnEditField_4Label = uilabel(app.PuntajedeclasificacinPanel);
            app.PeatnEditField_4Label.Text = 'Peatón';
            app.PeatnEditField_4 = uieditfield(app.PuntajedeclasificacinPanel, 'numeric');
            app.MEDIDASADOPTADASPanel = uipanel(app.MedidasdemejoramientoTab);
            app.MEDIDASADOPTADASPanel.Title = 'MEDIDAS ADOPTADAS';
            app.UITable2 = uitable(app.MEDIDASADOPTADASPanel);
            app.UITable2.ColumnName = {'#'; 'Contramedida'; 'Atributo analizado'; 'Categoría'; 'Selección'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.CalcularButton = uibutton(app.MEDIDASADOPTADASPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PDFButton_2 = uibutton(app.MEDIDASADOPTADASPanel, 'push');
            app.PDFButton_2.ButtonPushedFcn = createCallbackFcn(app, @PDFButton_2Pushed, true);
            app.PDFButton_2.Icon = 'icono-PDF.png';
            app.PDFButton_2.Text = 'PDF';
            app.PuntajedeclasificacinPanel_2 = uipanel(app.MEDIDASADOPTADASPanel);
            app.PuntajedeclasificacinPanel_2.Title = 'Puntaje de clasificación';
            app.OcupantedevehculosEditField_5Label = uilabel(app.PuntajedeclasificacinPanel_2);
            app.OcupantedevehculosEditField_5Label.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosEditField_5 = uieditfield(app.PuntajedeclasificacinPanel_2, 'numeric');
            app.CiclistaEditField_5Label = uilabel(app.PuntajedeclasificacinPanel_2);
            app.CiclistaEditField_5Label.Text = 'Ciclista';
            app.CiclistaEditField_5 = uieditfield(app.PuntajedeclasificacinPanel_2, 'numeric');
            app.MotociclistaEditField_5Label = uilabel(app.PuntajedeclasificacinPanel_2);
            app.MotociclistaEditField_5Label.Text = 'Motociclista';
            app.MotociclistaEditField_5 = uieditfield(app.PuntajedeclasificacinPanel_2, 'numeric');
            app.PeatnEditField_5Label = uilabel(app.PuntajedeclasificacinPanel_2);
            app.PeatnEditField_5Label.Text = 'Peatón';
            app.PeatnEditField_5 = uieditfield(app.PuntajedeclasificacinPanel_2, 'numeric');
            app.ClasificacinporestrellasPanel_2 = uipanel(app.MEDIDASADOPTADASPanel);
            app.ClasificacinporestrellasPanel_2.Title = 'Clasificación por estrellas';
            app.OcupantedevehculosEditField_6Label = uilabel(app.ClasificacinporestrellasPanel_2);
            app.OcupantedevehculosEditField_6Label.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosEditField_6 = uieditfield(app.ClasificacinporestrellasPanel_2, 'numeric');
            app.MotociclistaEditField_6Label = uilabel(app.ClasificacinporestrellasPanel_2);
            app.MotociclistaEditField_6Label.Text = 'Motociclista';
            app.MotociclistaEditField_6 = uieditfield(app.ClasificacinporestrellasPanel_2, 'numeric');
            app.CiclistaEditField_6Label = uilabel(app.ClasificacinporestrellasPanel_2);
            app.CiclistaEditField_6Label.Text = 'Ciclista';
            app.CiclistaEditField_6 = uieditfield(app.ClasificacinporestrellasPanel_2, 'numeric');
            app.PeatnEditField_6Label = uilabel(app.ClasificacinporestrellasPanel_2);
            app.PeatnEditField_6Label.Text = 'Peatón';
            app.PeatnEditField_6 = uieditfield(app.ClasificacinporestrellasPanel_2, 'numeric');
            app.GuardarButton = uibutton(app.MEDIDASADOPTADASPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            % Show the figure after all components are created
            app.Reporte.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_Reporte

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Reporte)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Reporte)
        end
    end
end